classdef HORUS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainScreen                      matlab.ui.container.Panel
        MainGrid                        matlab.ui.container.GridLayout
        PasswordHTML                    matlab.ui.control.HTML
        UserNameHTML                    matlab.ui.control.HTML
        ConectionCableCheckBox          matlab.ui.control.CheckBox
        AccederHTML                     matlab.ui.control.HTML
        CrearCuentaLink                 matlab.ui.control.Hyperlink
        SignInStateLBL                  matlab.ui.control.Label
        LOGOSEPA                        matlab.ui.control.Image
        SignInLBL                       matlab.ui.control.Label
        AnalisisScreen                  matlab.ui.container.Panel
        AnalisisGrid                    matlab.ui.container.GridLayout
        ExportHTML                      matlab.ui.control.HTML
        FrequencyLBL                    matlab.ui.control.Label
        DurationLBL                     matlab.ui.control.Label
        TimeLBL                         matlab.ui.control.Label
        FrecuenciaLabel                 matlab.ui.control.Label
        ObservacionesPanel              matlab.ui.container.Panel
        GridLayout_5                    matlab.ui.container.GridLayout
        ObsHTML                         matlab.ui.control.HTML
        AsimetriaIzquierdaDerechaPanel  matlab.ui.container.Panel
        GridLayout_4                    matlab.ui.container.GridLayout
        AsimetriaAxes                   matlab.ui.control.UIAxes
        MetricasclavePanel              matlab.ui.container.Panel
        GridLayout_3                    matlab.ui.container.GridLayout
        CorrelationButton               matlab.ui.control.Button
        AsimetriaButton                 matlab.ui.control.Button
        FijacionMediaButton             matlab.ui.control.Button
        ParpadeosButton                 matlab.ui.control.Button
        DurationmediaButton             matlab.ui.control.Button
        SacadastotalesButton            matlab.ui.control.Button
        TasadeparpadeoPanel             matlab.ui.container.Panel
        GridLayout_2                    matlab.ui.container.GridLayout
        TotalParpadeosLBL               matlab.ui.control.Label
        RateParpadeosLBL                matlab.ui.control.Label
        ParpadeostotalesLabel           matlab.ui.control.Label
        ParpadeosminLabel               matlab.ui.control.Label
        Gauge                           matlab.ui.control.SemicircularGauge
        CorrelacindeSpearmanPanel       matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        RresultLBL                      matlab.ui.control.Label
        CorrAxes                        matlab.ui.control.UIAxes
        DuracionLabel                   matlab.ui.control.Label
        FechaLabel                      matlab.ui.control.Label
        AnalisisEventosHTML             matlab.ui.control.HTML
        BackAnalisisHTML                matlab.ui.control.HTML
        Image_2                         matlab.ui.control.Image
        ANALISISEOGLabel                matlab.ui.control.Label
        ConectScreen                    matlab.ui.container.Panel
        ConectGrid                      matlab.ui.container.GridLayout
        BackConectHTML                  matlab.ui.control.HTML
        TomarMedidasHTML                matlab.ui.control.HTML
        BuscarDevicesHTML               matlab.ui.control.HTML
        LogoHorusHTML                   matlab.ui.control.HTML
        DevicesListHTML                 matlab.ui.control.HTML
        WifiStatusHTML                  matlab.ui.control.HTML
        ShowHiddenWifisCheckBox         matlab.ui.control.CheckBox
        AnalisisSignalsScreen           matlab.ui.container.Panel
        AnalisisSignalsGrid             matlab.ui.container.GridLayout
        BackAnalisisSignalsHTML         matlab.ui.control.HTML
        ResultadosAnalisisHTML          matlab.ui.control.HTML
        VerticalRExpandedHTML           matlab.ui.control.HTML
        VerticalLExpandedHTML           matlab.ui.control.HTML
        HorizontalRExpandedHTML         matlab.ui.control.HTML
        HorizontalLExpandedHTML         matlab.ui.control.HTML
        EYEL2                           matlab.ui.control.Image
        EYER2                           matlab.ui.control.Image
        RHorizontalAxis                 matlab.ui.control.UIAxes
        RVerticalAxis                   matlab.ui.control.UIAxes
        LHorizontalAxis                 matlab.ui.control.UIAxes
        LVerticalAxis                   matlab.ui.control.UIAxes
        ExpandedViewScreen              matlab.ui.container.Panel
        ExpandedViewGrid                matlab.ui.container.GridLayout
        SignalViewSlider                matlab.ui.control.Slider
        SignalViewLabel                 matlab.ui.control.Label
        BackExpandedViewHTML            matlab.ui.control.HTML
        ExpandedPlot                    matlab.ui.control.UIAxes
        GraficasScreen                  matlab.ui.container.Panel
        GraficasGrid                    matlab.ui.container.GridLayout
        StartStopHTML                   matlab.ui.control.HTML
        FSLabel                         matlab.ui.control.Label
        BackGraficasHTML                matlab.ui.control.HTML
        AnalizarMedidasHTML             matlab.ui.control.HTML
        EYEL                            matlab.ui.control.Image
        EYER                            matlab.ui.control.Image
        ConectedLampGrid                matlab.ui.container.GridLayout
        ConectedLamp                    matlab.ui.control.Lamp
        ConectedLBL                     matlab.ui.control.Label
        VEOL                            matlab.ui.control.UIAxes
        HEOR                            matlab.ui.control.UIAxes
        HEOL                            matlab.ui.control.UIAxes
        VEOR                            matlab.ui.control.UIAxes
        EventsAnalisisScreen            matlab.ui.container.Panel
        EventsAnalisisGrid              matlab.ui.container.GridLayout
        BackEventsAnalisisHTML          matlab.ui.control.HTML
        EventsTable                     matlab.ui.control.Table
        EventsAnalisisLBL               matlab.ui.control.Label
        ChooseActionScreen              matlab.ui.container.Panel
        ChooseActionGrid                matlab.ui.container.GridLayout
        BackChooseActionHTML            matlab.ui.control.HTML
        ConectarGafasHTML               matlab.ui.control.HTML
        VerAnalisisPreviosHTML          matlab.ui.control.HTML
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
    end


    properties (Access = private)
        usuario
        password
        AvailableWifis
        DevicesWifis
        WifiInterface
        PasswordOptions = ["zeri3333","wifi.ROS1","00000000","12345678"];
        Device
        SHEOL
        SVEOL
        SHEOR
        SVEOR
        ColorSHEOR = '#00b4d8'
        ColorSVEOL = '#d62828'
        ColorSHEOL = '#6a0dad'
        ColorSVEOR = '#2dc653'
        LineSHEOL = []
        LineSVEOL = []
        LineSHEOR = []
        LineSVEOR = []
        NoDataCount     % contador de intentos sin datos
        TimerGraficar        % timer de adquisición
        counter               % contador de muestras
        lastTime
        fs_est
        fs_fixed
        fs_initial = 250.00
        fs_calibrating = true
        fs_startTime
        FilterState
        SHEOL_f              % H EOL filtered signal (mV)
        SVEOL_f              % V EOL filtered signal (mV)
        SHEOR_f              % H EOR filtered signal (mV)
        SVEOR_f              % V EOR filtered signal (mV)
        SignalsFiltered_OK = false   % flag: true if post filtering was successful
        counter_fs = 0
        yMaxSticky = 50   % valor inicial mínimo en mV (evita escala 0 al inicio)
        % Señales globales para análisis
        HEOG_global
        VEOG_global

        % Resultados de sacadas
        SaccadesMetrics
        StartAppearance = struct( ...
            'bgColor',      '#b8f0c8', ...
            'textColor',    '#1a4a2e', ...
            'borderColor',  '#6dcea0', ...
            'borderWidth',  '2px',     ...
            'borderRadius', '14px',    ...
            'label',        'Start'    ...
            )
        StopAppearance = struct( ...
            'bgColor',      '#f7c1c1', ...
            'textColor',    '#6b1a1a', ...
            'borderColor',  '#d47a7a', ...
            'borderWidth',  '2px',     ...
            'borderRadius', '14px',    ...
            'label',        'Stop'     ...
            )
        ExpandedSignalTime
        ExpandedViewWindow    = 12      % segundos visibles a la vez
        crearcuenta = false
    end

    methods (Access = private)
        % Funcion para cambiar de ventana
        function ChangeWindowTo(app,screen)
            screens = [...
                app.MainScreen,...
                app.ChooseActionScreen,...
                app.ConectScreen,...
                app.GraficasScreen,...
                app.AnalisisScreen,...
                app.EventsAnalisisScreen,...
                app.AnalisisSignalsScreen,...
                app.ExpandedViewScreen
                ];
            for a = screens
                a.Visible = 'off';
            end
            screen.Visible = 'on';
        end
        % Funcion para prepararse a la conexion de wifi
        function PrepareWifi(app)
            wifiName = "";
            if ispc

                % detectar adaptador wifi (incluso deshabilitado)
                cmd = ['powershell -Command "', ...
                    'Get-NetAdapter | Where-Object {$_.NdisPhysicalMedium -eq 9} | Select-Object -First 1 -ExpandProperty Name"'];

                [~,out] = system(cmd);
                wifiName = strtrim(out);

                if wifiName == ""
                    error("No se encontró adaptador WiFi");
                end
                % comprobar estado
                [~,interfaces] = system('netsh interface show interface');

                if ~contains(interfaces,"Enabled")

                    cmd = sprintf('netsh interface set interface "%s" admin=enabled',wifiName);
                    system(cmd);

                end
                % desconectar wifi actual
                system('netsh wlan disconnect');
            else
                app.WifiInterface = wifiName;
                error("Sistema operativo no soportado")
            end
            app.WifiInterface = wifiName;
        end
        % Funcion para hacer un display de las redes wifi disponibles
        function FindAvailableWifis(app)
            app.AvailableWifis = strings(0);
            ssids = strings(0);

            if ~ispc
                error("Sistema operativo no soportado")
            end

            % Disable/enable para forzar rescan real
            psCommand = sprintf([...
                '$wifi = Get-NetAdapter -Name "%s"; ' ...
                'Disable-NetAdapter -Name $wifi.Name -Confirm:$false; ' ...
                'Start-Sleep -Seconds 2; ' ...
                'Enable-NetAdapter -Name $wifi.Name -Confirm:$false;'], app.WifiInterface);

            [status, out] = system(['powershell -Command "', psCommand, '"']);
            if status ~= 0
                error("Error forzando reescaneo Wi-Fi:\n%s", out);
            end

            % Esperar a que el adaptador este habilitado
            ready = false;
            for k = 1:10
                pause(1)
                [~, state] = system('netsh interface show interface');
                lines = splitlines(state);
                wifiLine = lines(contains(lines, app.WifiInterface));
                if ~isempty(wifiLine) && contains(wifiLine, ["Enabled","Habilitado"])
                    ready = true;
                    break
                end
            end

            if ~ready
                error("La interfaz WiFi no se activó correctamente")
            end

            %Esperar scan fresco: repetir hasta que dos resultados ──
            %consecutivos sean idénticos (descarta la caché vieja)
            pause(2);   % margen mínimo antes del primer scan

            previousSSIDs = strings(0);
            stableCount   = 0;

            for attempt = 1:12   % máx ~18 s de espera
                [status, out] = system('netsh wlan show networks mode=bssid');

                if status ~= 0
                    pause(1.5);
                    continue
                end

                % Parseo robusto (cubre ES/EN, con o sin número en SSID)
                currentSSIDs = strings(0);
                lines = splitlines(out);
                for i = 1:length(lines)
                    tok = regexp(strtrim(lines{i}), ...
                        '^SSID\s*\d*\s*:\s*(.+)$', 'tokens', 'once');
                    if ~isempty(tok)
                        ssid = strtrim(tok{1});
                        % Excluir BSSIDs (tienen ":" de dirección MAC)
                        if ~isempty(ssid) && ~contains(ssid, ':')
                            currentSSIDs(end+1) = ssid;
                        end
                    end
                end
                currentSSIDs = unique(currentSSIDs);

                % Comparar con el scan anterior
                if isequal(sort(currentSSIDs), sort(previousSSIDs)) && ~isempty(currentSSIDs)
                    stableCount = stableCount + 1;
                    if stableCount >= 2   % dos scans iguales = resultado estable
                        ssids = currentSSIDs;
                        break
                    end
                else
                    stableCount   = 0;
                    previousSSIDs = currentSSIDs;
                end

                pause(1.5);
            end

            if isempty(ssids)
                ssids = previousSSIDs;   % usar lo último que se obtuvo
            end

            ssids = unique(ssids);
            app.AvailableWifis = flip(ssids(ssids ~= ""));
        end
        % Funcion para recargar lista de wifis
        function ReloadListWifis(app,listWifis)
            % Vaciamos y volvemos a cargar con data nueva
            app.DevicesListHTML.Data = {};
            app.DevicesListHTML.Data = listWifis;
        end
        function createWifiProfile(~,ssid,password)
            system("netsh wlan delete profile name=""" + ssid + """")
            WifiTemplate = fileread("wifi_template.xml");

            profile = strrep(WifiTemplate,"SSID_AQUI",ssid);
            profile = strrep(profile,"PASSWORD_AQUI",password);

            fid = fopen("wifi_temp.xml",'w');
            fprintf(fid,profile);
            fclose(fid);

            system('netsh wlan add profile filename="wifi_temp.xml" user=current');

        end
        % Funciion para cambiar el estado del wifi
        function ChangeWifiStatus (app,state)
            app.WifiStatusHTML.Data = state;
        end
        % Funcion para recargar las redes wifi
        function RefreshWifis(app)
            % Cambiamos el estado de la wifi a desconectado
            ChangeWifiStatus(app,'desconectado');
            app.TomarMedidasHTML.Visible = 'off';
            ReloadListWifis(app,{});
            LoadingWifis = uiprogressdlg(app.UIFigure, ...
                'Title', 'Buscando Dispositivos...', 'Message', 'Porfavor espere mientras se cargan los dispositivos...', 'Indeterminate', 'on');
            drawnow
            % Buscamos wifis disponibles
            FindAvailableWifis(app);
            disp(app.AvailableWifis);
            % Revisamos si se ha encontrado alguna red
            if isempty(app.AvailableWifis)
                close(LoadingWifis);
                uialert(app.UIFigure,'Compruebe que el Dispositivo este encendido','Ninguna Wifi Encontrada');
            else
                %const char* ssid = "ESP32SEPA"; // NOMBRE DE LA RED A CREAR
                %const char* password = "zeri3333";   // PASSWORD DE LA RED (MINIMO 8CHAR)
                comparador = contains(app.AvailableWifis, "ESP32", "IgnoreCase", true);
                app.DevicesWifis = app.AvailableWifis(comparador);
                if isempty(app.DevicesWifis)
                    uialert(app.UIFigure,'Compruebe que el Dispositivo este encendido o busque en Redes Escondidas','Ningun Dispositivo Encontrado','Icon','warning');
                end
                ReloadListWifis(app, app.DevicesWifis);
                close(LoadingWifis);
            end
        end
        % Funcion para realizar la conexion entre los datos que recoge la
        % ESP32 con MATLAB por una red wifi
        function DataConnection(app)
            try
                if ~isempty(app.Device)
                    delete(app.Device);
                    app.Device = [];   % limpia la conexión anterior
                    pause(0.1);
                end
                app.Device = tcpclient("192.168.4.1",5000);
                disp('Conectado a ESP32');
                app.ConectedLamp.Color = 'green';
                app.StartStopHTML.Visible = 'on';

            catch
                app.Device = [];  % devuelve vacío si falla
                app.ConectedLamp.Color = 'red';
                app.StartStopHTML.Visible = 'off';
            end
        end
        % Funcion para realizar la conexion entre los datos que recoge la
        % ESP32 con MATLAB por cable
        function DataConnectionCable(app)
            try
                if ~isempty(app.Device)
                    app.Device = [];   % limpia la conexión anterior
                    pause(0.1);
                end
                % https://es.mathworks.com/help/matlab/import_export/read-streaming-data-from-arduino.html
                % Cerramos las conexiones serialport existentes
                delete(serialportfind);
                % Guardamos los datos del serialport en "serialObj"
                app.Device = serialport("COM5",500000);
                % Definimos la propiedad Terminator para que coincida con el terminador
                % especificado en el codigo de arduino
                configureTerminator(app.Device,"CR/LF");
                % Vaciamos serialObj para limpiar datos antiguos
                flush(app.Device);
                app.ConectedLamp.Color = 'green';
                app.StartStopHTML.Visible = 'on';
            catch
                app.Device = [];  % devuelve vacío si falla
                uialert(app.UIFigure,'Compruebe que el Dispositivo este encendido','Ningun Dispositivo Conectado');
                app.ConectedLamp.Color = 'red';
                app.StartStopHTML.Visible = 'off';
            end

        end

        % Funcion para adquirir y  los datos recogidos previamente
        function DataAcquisition(app)
            ResetSignals(app);
            % Crear líneas animadas
            app.LineSHEOL = animatedline(app.HEOL,  'Color', app.ColorSHEOL);
            app.LineSVEOL = animatedline(app.VEOL,  'Color', app.ColorSVEOL);
            app.LineSHEOR = animatedline(app.HEOR,  'Color', app.ColorSHEOR);
            app.LineSVEOR = animatedline(app.VEOR,  'Color', app.ColorSVEOR);
            % YLim inicial centrado en 0, fijo
            app.yMaxSticky = 100;
            lim = [-100, 100];
            app.HEOL.YLim = lim;  app.HEOL.YLimMode = 'manual';
            app.VEOL.YLim = lim;  app.VEOL.YLimMode = 'manual';
            app.HEOR.YLim = lim;  app.HEOR.YLimMode = 'manual';
            app.VEOR.YLim = lim;  app.VEOR.YLimMode = 'manual';
            app.counter           = 0;
            app.NoDataCount = 0;
            windowSize      = 600;

            % Crear timer que ejecuta el callback cada 20ms (50Hz)
            app.TimerGraficar = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period',         0.04, ...
                'TimerFcn',       @(~,~) GraficarDatos(app, windowSize), ...
                'ErrorFcn',       @(~,~) StopAcquisition(app, 'error'));

            start(app.TimerGraficar);
        end
        function GraficarDatos(app, windowSize)
            try
                huboDatos = false;

                while app.Device.NumBytesAvailable > 0
                    data   = readline(app.Device);
                    valores = str2double(split(strtrim(data), ","));

                    if length(valores) == 4
                        fprintf('raw: %.0f  %.0f  %.0f  %.0f\n', ...
                            valores(1),valores(2),valores(3),valores(4));

                        huboDatos = true;   % ← marcar que llegaron datos válidos

                        s1 = valores(1);  s2 = valores(2);
                        s3 = valores(3);  s4 = valores(4);

                        S1mV = s1 * 0.80586080586;
                        S2mV = s2 * 0.80586080586;
                        S3mV = s3 * 0.80586080586;
                        S4mV = s4 * 0.80586080586;

                        if app.fs_calibrating
                            S1f = eogRealtimeFilter(app, S1mV, app.fs_initial, 1);
                            S2f = eogRealtimeFilter(app, S2mV, app.fs_initial, 2);
                            S3f = eogRealtimeFilter(app, S3mV, app.fs_initial, 3);
                            S4f = eogRealtimeFilter(app, S4mV, app.fs_initial, 4);
                        else
                            S1f = eogRealtimeFilter(app, S1mV, app.fs_fixed, 1);
                            S2f = eogRealtimeFilter(app, S2mV, app.fs_fixed, 2);
                            S3f = eogRealtimeFilter(app, S3mV, app.fs_fixed, 3);
                            S4f = eogRealtimeFilter(app, S4mV, app.fs_fixed, 4);
                        end

                        updateFS(app);
                        tickMax = max(abs([S1f, S2f, S3f, S4f]));
                        updateYLim(app, tickMax);

                        app.SHEOL(end+1) = S1mV; %cable naranja
                        app.SVEOL(end+1) = S2mV; %cable morado
                        app.SHEOR(end+1) = S3mV; %cable amarillo
                        app.SVEOR(end+1) = S4mV; %cable verde

                        app.counter = app.counter + 1;

                        addpoints(app.LineSHEOL, app.counter, S1f)
                        addpoints(app.LineSVEOL, app.counter, S2f)
                        addpoints(app.LineSHEOR, app.counter, S3f)
                        addpoints(app.LineSVEOR, app.counter, S4f)

                        if app.counter > windowSize
                            app.HEOL.XLim = [app.counter - windowSize, app.counter];
                            app.VEOL.XLim = [app.counter - windowSize, app.counter];
                            app.HEOR.XLim = [app.counter - windowSize, app.counter];
                            app.VEOR.XLim = [app.counter - windowSize, app.counter];
                        end
                    end
                end

                % Actualización visual
                if app.counter > 0
                    app.FSLabel.Text = sprintf('FS: %.1f Hz', app.fs_est);
                    drawnow limitrate
                end

                % Timeout: solo contar ticks donde no llegó NINGÚN dato válido
                if huboDatos
                    app.NoDataCount = 0;         % llegaron datos, se resetea
                else
                    app.NoDataCount = app.NoDataCount + 1;   % tick vacío, se acumula
                    if app.NoDataCount > 125     % 125 ticks × 40ms = 5 segundos
                        StopAcquisition(app, 'timeout');
                    end
                end

            catch
                StopAcquisition(app, 'error');
            end
        end

        function StopAcquisition(app, reason)

            if ~isempty(app.TimerGraficar) && isvalid(app.TimerGraficar)
                stop(app.TimerGraficar);
                delete(app.TimerGraficar);
                app.TimerGraficar = [];
            end
            app.AnalizarMedidasHTML.Visible = "on";

            switch reason
                case 'manual'
                    if ~isempty(app.SHEOL)
                        filterSignalsPost(app);
                    end

                case 'timeout'
                    uialert(app.UIFigure, 'Device stopped sending data.', ...
                        'Connection lost', 'Icon', 'warning');
                    app.ConectedLamp.Color = 'red';
                    app.Device = [];

                case 'error'
                    uialert(app.UIFigure, 'Error reading data from device.', ...
                        'Error', 'Icon', 'error');
                    app.ConectedLamp.Color = 'red';
                    app.Device = [];
            end
        end

        function y = eogRealtimeFilter(app, x, fs, ch)
            key = sprintf('ch%d', ch);

            if isempty(app.FilterState) || ~isfield(app.FilterState, key)
                [b_hp, a_hp] = butter(2, 0.2/(fs/2), 'high');
                [b_lp, a_lp] = butter(4, 10/(fs/2),  'low');
                if any(abs(roots(a_lp)) >= 1)
                    [b_lp, a_lp] = butter(2, 10/(fs/2), 'low');
                end

                app.FilterState.(key) = struct( ...
                    'b_hp',    b_hp, ...
                    'a_hp',    a_hp, ...
                    'z_hp',    zeros(2,1), ...
                    'b_lp',    b_lp, ...
                    'a_lp',    a_lp, ...
                    'z_lp',    zeros(max(length(a_lp),length(b_lp))-1, 1), ...
                    'sat_buf', x * ones(round(fs * 0.5), 1), ...
                    'vel_buf', zeros(round(fs * 0.05), 1), ... % buffer 50ms velocidad
                    'prev_y',  0);
            end

            s = app.FilterState.(key);

            % Saturación rail-to-rail → cero
            s.sat_buf = [s.sat_buf(2:end); x];
            pct_high  = mean(s.sat_buf > 3290);
            pct_low   = mean(s.sat_buf <   10);
            if pct_high > 0.5 || (pct_high + pct_low) > 0.4
                app.FilterState.(key) = s;
                y = 0;
                return
            end

            % Filtrado IIR causal
            [x_hp, s.z_hp] = filter(s.b_hp, s.a_hp, x, s.z_hp);
            [y_f,  s.z_lp] = filter(s.b_lp, s.a_lp, x_hp, s.z_lp);

            % Deadband en tiempo real: velocidad media en ventana 50ms
            vel_inst    = abs(y_f - s.prev_y) * fs;
            s.vel_buf   = [s.vel_buf(2:end); vel_inst];
            vel_mean    = mean(s.vel_buf);
            s.prev_y    = y_f;

            % Si velocidad baja Y amplitud pequeña → ruido → devolver 0
            if vel_mean < 80 && abs(y_f) < 100
                y = 0;
            else
                y = y_f;
            end

            app.FilterState.(key) = s;
        end

        function updateFS(app)
            % Inicialización
            if isempty(app.lastTime)
                app.lastTime       = tic;
                app.fs_est         = 0;
                app.fs_startTime   = tic;
                app.counter_fs     = 0;
                return;
            end

            app.counter_fs = app.counter_fs + 1;
            elapsed = toc(app.lastTime);

            % Actualizar estimación cada 0.5 segundos
            if elapsed >= 0.5
                app.fs_est   = app.counter_fs / elapsed;
                app.lastTime = tic;
                app.counter_fs = 0;
            end

            % Fijar fs tras 2 segundos de calibración
            if app.fs_calibrating
                if toc(app.fs_startTime) > 2
                    if app.fs_est > 10   % fs razonable antes de fijar
                        app.fs_fixed      = round(app.fs_est);
                        app.fs_calibrating = false;
                        app.FilterState    = struct();
                        fprintf('FS FIJADO: %.2f Hz\n', app.fs_fixed);
                    end
                end
            end
        end
        function updateYLim(app, newValue)
            absVal = abs(newValue);
            if absVal > app.yMaxSticky
                % Añadir 20% de margen y redondear para no reescalar en cada sample
                app.yMaxSticky = ceil(absVal * 1.2 / 50) * 50;
                lim = [-app.yMaxSticky, app.yMaxSticky];
                app.HEOL.YLim = lim;
                app.VEOL.YLim = lim;
                app.HEOR.YLim = lim;
                app.VEOR.YLim = lim;
            end
        end

        function filterSignalsPost(app)
            % FILTERSIGNALSPOST  Pipeline de filtrado post-adquisición para señales EOG.
            %
            % Aplica una cadena de 5 etapas sobre las 4 señales crudas almacenadas
            % en app.SHEOL/SVEOL/SHEOR/SVEOR y guarda el resultado en los campos _f.
            %
            % Pipeline:
            %   1. High-pass Butterworth 0.2 Hz  → elimina deriva DC y de electrodo
            %   2. Notch IIR 50/100 Hz           → elimina interferencia de red eléctrica
            %   3. Low-pass Butterworth 10 Hz    → elimina ruido EMG y electrónico
            %   4. Savitzky-Golay ~80 ms         → suavizado preservando forma de sacada
            %   5. Deadband amplitud+velocidad   → suprime ruido residual en fijaciones
            %
            % Al completarse correctamente, activa app.SignalsFiltered_OK = true.

            app.SignalsFiltered_OK = false;

            % ----------------------------------------------------------------
            % 1. VALIDACIÓN
            % ----------------------------------------------------------------
            % Agrupa las 4 señales en un cell array para procesarlas en bucle.
            % Verifica que ninguna esté vacía antes de empezar — si falta una
            % señal, el pipeline no tiene sentido y se aborta.
            raw_signals = {app.SHEOL, app.SVEOL, app.SHEOR, app.SVEOR};
            ch_names    = {'SHEOL', 'SVEOL', 'SHEOR', 'SVEOR'};

            for k = 1:4
                if isempty(raw_signals{k})
                    warning('filterSignalsPost: %s vacío. Abortando.', ch_names{k});
                    return
                end
            end

            % fs_fixed se establece durante la calibración de los primeros 2s.
            % Si todavía está calibrando o no hay fs válida, no podemos diseñar
            % filtros (los coeficientes Butterworth dependen de fs).
            if app.fs_calibrating || isempty(app.fs_fixed) || app.fs_fixed <= 0
                warning('filterSignalsPost: fs_fixed no disponible. Abortando.');
                return
            end

            fs  = app.fs_fixed;
            nyq = fs / 2;   % frecuencia de Nyquist — límite superior usable

            % Recorta todas las señales a la longitud de la más corta.
            % Puede haber diferencias de 1-2 muestras por la latencia del timer.
            N_min = min(cellfun(@length, raw_signals));
            for k = 1:4
                raw_signals{k} = raw_signals{k}(1:N_min);
            end

            fprintf('\n=== POST-ACQUISITION FILTER (HORUS) ===\n');
            fprintf('  fs_fixed : %d Hz\n', fs);
            fprintf('  Muestras : %d por canal  (%.2f s)\n', N_min, N_min/fs);

            % ----------------------------------------------------------------
            % 2. DISEÑO DE FILTROS
            % ----------------------------------------------------------------
            % Los filtros se diseñan UNA sola vez aquí y se reutilizan
            % para los 4 canales en el bucle de abajo. Más eficiente que
            % diseñarlos dentro del bucle.

            % ── High-pass 0.2 Hz ─────────────────────────────────────────────
            % Elimina la componente DC (~1650 mV del AD8232) y la deriva lenta
            % del electrodo (movimiento, sudor). Corte a 0.2 Hz = periodos >5s.
            % Butterworth orden 2: con filtfilt equivale a orden 4 efectivo
            % (aplicación de dos pasadas → fase cero, sin distorsión temporal).
            fc_hp = 0.2;
            Wn_hp = fc_hp / nyq;
            % Protección: si fs es muy baja, fc_hp podría superar Nyquist
            if Wn_hp >= 1; fc_hp = 0.1; Wn_hp = fc_hp/nyq; end
            [b_hp, a_hp] = butter(2, Wn_hp, 'high');

            % ── Notch 50 Hz y 100 Hz ─────────────────────────────────────────
            % Elimina la interferencia de red eléctrica (50 Hz en Europa) y su
            % primer armónico (100 Hz). Solo se añade el notch si la frecuencia
            % está por debajo de Nyquist con margen de 2 Hz de seguridad.
            % Q ≈ 35 → banda de rechazo muy estrecha (~1.4 Hz), mínima
            % distorsión a frecuencias adyacentes.
            notch_filters = {};
            for fn = [50, 100]
                if fn < nyq - 2
                    wo = fn / nyq;                   % frecuencia normalizada [0,1]
                    [b_n, a_n] = iirnotch(wo, wo/35);
                    notch_filters{end+1} = {b_n, a_n};
                    fprintf('  Notch    : %.0f Hz\n', fn);
                end
            end

            % ── Low-pass 10 Hz ───────────────────────────────────────────────
            % Elimina ruido EMG (músculo orbicular, masetero) e interferencias
            % eléctricas de alta frecuencia. La banda EOG útil es 0–8 Hz
            % (sacadas). 10 Hz da margen sin cortar energía real de sacada.
            % Orden 4 con filtfilt = caída efectiva de -80 dB/dec.
            % Si el filtro resulta inestable (polo fuera del círculo unitario),
            % se degrada a orden 2 como fallback seguro.
            fc_lp    = min(10, nyq - 2);   % nunca superar Nyquist
            order_lp = 4;
            [b_lp, a_lp] = butter(order_lp, fc_lp/nyq, 'low');
            if any(abs(roots(a_lp)) >= 1)  % comprobación de estabilidad
                [b_lp, a_lp] = butter(2, fc_lp/nyq, 'low');
            end

            % ── Savitzky-Golay ───────────────────────────────────────────────
            % Suavizado local por ajuste polinomial (grado 3) en ventana de ~80ms.
            % A diferencia de una media móvil, preserva los picos y la forma
            % exacta de cada sacada (onset nítido, pico de velocidad conservado).
            % La ventana debe ser impar → se fuerza con el floor×2+1.
            sgWin = 2 * floor(fs * 0.08 / 2) + 1;  % ~80 ms en muestras
            sgWin = max(sgWin, 5);                  % mínimo 5 muestras

            fprintf('  Highpass : Butterworth 2 ord  |  fc = %.2f Hz\n', fc_hp);
            fprintf('  Lowpass  : Butterworth 4 ord  |  fc = %.1f Hz\n',  fc_lp);
            fprintf('  SGolay   : orden 3  |  ventana %d muestras (%.0f ms)\n', ...
                sgWin, sgWin/fs*1000);

            % ----------------------------------------------------------------
            % 3. PIPELINE POR CANAL
            % ----------------------------------------------------------------
            filtered_signals = cell(4,1);

            for k = 1:4
                sig = double(raw_signals{k}(:));  % forzar columna y tipo double

                % ── Guardia de saturación ─────────────────────────────────────
                % Si >50% de las muestras están en el rail alto (>3290 mV ≈ 99%
                % de 3.3V) o más del 40% combinado entre ambos railes, el canal
                % está saturado (electrodo despegado o cortocircuito).
                % No tiene sentido filtrar → devolver canal de ceros.
                pct_high = mean(sig > 3290);
                pct_low  = mean(sig <   10);
                if pct_high > 0.5 || (pct_high + pct_low) > 0.4
                    filtered_signals{k} = zeros(N_min, 1);
                    fprintf('  [%s]  Saturado → zeros\n', ch_names{k});
                    continue
                end

                % ── Guardia de señal plana ────────────────────────────────────
                % Después de restar la mediana (para eliminar DC), si el RMS
                % es < 0.5 mV el canal está esencialmente muerto (electrodo
                % sin contacto o canal no conectado en el ESP32).
                sig_dc = sig - median(sig);
                if rms(sig_dc) < 0.5
                    filtered_signals{k} = zeros(N_min, 1);
                    fprintf('  [%s]  Sin señal → zeros\n', ch_names{k});
                    continue
                end

                % ── A: High-pass 0.2 Hz ──────────────────────────────────────
                % filtfilt: aplica el filtro en ambas direcciones temporales,
                % resultando en fase cero (sin retraso) y orden efectivo doble.
                % Elimina DC (~1650 mV) y deriva lenta del electrodo.
                sig = filtfilt(b_hp, a_hp, sig);

                % ── B: Notch 50/100 Hz ───────────────────────────────────────
                % Se aplica en bucle por si hay múltiples notch activos.
                % Elimina interferencia de red eléctrica sin afectar la banda EOG.
                for n = 1:length(notch_filters)
                    sig = filtfilt(notch_filters{n}{1}, notch_filters{n}{2}, sig);
                end

                % ── C: Low-pass 10 Hz ────────────────────────────────────────
                % Elimina ruido de alta frecuencia: EMG, ruido ADC, interferencias.
                % Deja solo la banda útil para EOG (0–10 Hz).
                sig = filtfilt(b_lp, a_lp, sig);

                % ── D: Savitzky-Golay ─────────────────────────────────────────
                % Suavizado final que preserva la forma exacta de las sacadas.
                % El orden 3 es óptimo para señales con pendientes lineales
                % (rampas de sacada) sin sobreajustar.
                sig = sgolayfilt(sig, 3, sgWin);

                % ── E: Deadband post ──────────────────────────────────────────
                % Suprime el ruido residual en periodos de fijación (ojo quieto).
                % Detecta zonas de baja velocidad Y baja amplitud → las interpola
                % linealmente para que el detector de sacadas no las confunda
                % con movimiento real. No afecta a sacadas genuinas.
                sig = eogDeadbandPost(app, sig, fs);

                filtered_signals{k} = sig;

                fprintf('  [%s]  RMS=%.2f mV  |  max=%.0f mV\n', ...
                    ch_names{k}, rms(sig), max(abs(sig)));
            end

            % ----------------------------------------------------------------
            % 4. GUARDAR
            % ----------------------------------------------------------------
            % Las señales filtradas se guardan en propiedades separadas de las
            % crudas para que ambas estén disponibles (exportación, comparación).
            app.SHEOL_f = filtered_signals{1};  % Horizontal Left  filtrada
            app.SVEOL_f = filtered_signals{2};  % Vertical Left    filtrada
            app.SHEOR_f = filtered_signals{3};  % Horizontal Right filtrada
            app.SVEOR_f = filtered_signals{4};  % Vertical Right   filtrada

            app.SignalsFiltered_OK = true;      % habilita botón "Analizar"
            fprintf('=========================================\n');
            fprintf('  Filtrado completo. Listo para análisis.\n\n');
        end

        function sig_out = eogDeadbandPost(~, sig, fs)
            % EOGDEADBANDPOST
            % Suprime zonas de ruido estático preservando movimientos oculares reales.
            %
            % ── POR QUÉ SE SUPRIMEN LAS SEÑALES PEQUEÑAS ────────────────────────
            % Esta decisión es técnicamente correcta con el hardware utilizado:
            %
            %   · AD8232 a ~100× ganancia, ADC 12-bit a 3.3V → resolución ~0.8 mV/LSB
            %   · El suelo de ruido post-filtrado es del orden de 2-8 mV RMS.
            %   · Movimientos oculares pequeños (<3° ángulo visual) generan
            %     potenciales corneoretinales de 10-40 µV, que tras la ganancia
            %     dan 1-4 mV: indistinguibles del ruido con esta electrónica.
            %   · Por tanto, señales por debajo de ~3σ del ruido medido NO son
            %     fiables como movimiento real y sí generan falsos positivos en
            %     el detector de sacadas si no se suprimen.
            %
            % La corrección respecto a la versión anterior no es QUITAR el deadband
            % sino hacer que sus umbrales sean ADAPTATIVOS al ruido real de cada
            % sesión en lugar de usar constantes absolutas de 100 mV que eliminaban
            % también sacadas medianas de 5-8° (40-80 mV con esta ganancia).
            %
            % ── CRITERIO DE MOVIMIENTO REAL (ambos deben cumplirse) ─────────────
            %   · Velocidad suavizada > velTh  (~4σ del ruido de velocidad)
            %   · Amplitud absoluta   > ampTh  (~3σ del ruido de amplitud)
            %
            % Las zonas sin movimiento real se interpolan linealmente entre los
            % puntos de movimiento vecinos para un baseline suave y continuo.
            %
            % ── BUGS CORREGIDOS vs versión anterior ─────────────────────────────
            %   #1  Umbrales adaptativos MAD de dos pasadas
            %         (eran 100 mV/s y 100 mV absolutos fijos — aplastaban sacadas
            %          medianas y solo dejaban pasar sacadas grandes >10°)
            %   #2  Sin extrapolación fuera del rango conocido
            %         (era interp1(...,'extrap') — generaba valores extremos si hay
            %          artefactos al inicio/final de la señal)
            %   #3  gradient() en lugar de [0; diff(sig)]*fs
            %         (el diff introducía un cero artificial en el sample 1)
            %   #4  Ventana de velocidad 35 ms en lugar de 80 ms
            %         (con 80 ms las sacadas cortas quedaban dentro de la ventana
            %          y su pico de velocidad se aplastaba hasta caer bajo el umbral)
            %   #5  Hold time 250 ms coherente (era 400 ms; el comentario decía 350 ms)
            %   #6  Extensión pre-onset de 20 ms hacia atrás
            %         (captura la rampa de aceleración antes de cruzar velTh,
            %          evitando que el inicio de la sacada quede interpolado como ruido)

            sig = sig(:);
            N   = length(sig);

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 1 — VELOCIDAD SUAVIZADA
            % ══════════════════════════════════════════════════════════════════════
            % FIX #3: gradient() calcula diferencias centrales simétricas en todos
            % los puntos. El anterior [0; diff(sig)]*fs ponía velocidad=0 en el
            % primer sample, invisibilizando movimientos al inicio de la grabación.
            %
            % FIX #4: Ventana de suavizado 35 ms (era 80 ms).
            % La función de la ventana es promediar la velocidad para reducir el
            % efecto del ruido de alta frecuencia. Con 80 ms:
            %   · A 250 Hz → 21 muestras de ventana.
            %   · Una sacada de 20-30 ms tiene ≤8 muestras de movimiento real
            %     rodeadas por 13+ muestras de baseline, aplastando el pico.
            % Con 35 ms (9 muestras a 250 Hz) el suavizado elimina ruido puntual
            % sin aplanar sacadas de duración corta.

            winVel    = 2 * floor(fs * 0.035 / 2) + 1;   % FIX #4: 35 ms (era 80 ms)
            winVel    = max(winVel, 5);
            vel       = abs(gradient(sig) * fs);           % FIX #3: gradient()
            velSmooth = movmean(vel, winVel);

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 2 — UMBRALES ADAPTATIVOS (dos pasadas MAD)
            % ══════════════════════════════════════════════════════════════════════
            % FIX #1: Los umbrales originales (velTh=100 mV/s, ampTh=100 mV) eran
            % constantes absolutas sin relación con la calidad de la señal real.
            % Con el AD8232 a ~100× y C4=4.7µF, una sacada de 5° produce ~50 mV
            % de amplitud y ~200 mV/s de velocidad pico (post-filtro). El umbral
            % de 100 mV eliminaba directamente sacadas medianas.
            %
            % Método MAD de dos pasadas (idéntico al de detectarEventosOculares):
            %   Pasada 1 → umbral rough para identificar zonas de quietud
            %   Pasada 2 → MAD calculado solo sobre esas zonas de quietud
            % Esto evita que las propias sacadas inflen el MAD.

            % ── Umbral de amplitud ────────────────────────────────────────────────
            amp_med0 = median(abs(sig));
            amp_mad0 = median(abs(abs(sig) - amp_med0));
            th_amp_r = amp_med0 + 6 * amp_mad0 * 1.4826;   % umbral rough
            quiet_a  = abs(sig) < th_amp_r;
            if sum(quiet_a) < 10
                quiet_a = true(N, 1);   % fallback si señal muy activa
            end
            amp_noise = median(abs(sig(quiet_a) - median(sig(quiet_a)))) * 1.4826;
            ampTh     = max(amp_noise * 3.0, 12);   % 3σ, mínimo 12 mV

            % ── Umbral de velocidad ───────────────────────────────────────────────
            vel_med0 = median(velSmooth);
            vel_mad0 = median(abs(velSmooth - vel_med0));
            th_vel_r = vel_med0 + 6 * vel_mad0 * 1.4826;   % umbral rough
            quiet_v  = velSmooth < th_vel_r;
            if sum(quiet_v) < 10
                quiet_v = true(N, 1);
            end
            vel_noise = median(abs(velSmooth(quiet_v) - median(velSmooth(quiet_v)))) * 1.4826;
            velTh     = max(vel_noise * 4.0, 20);   % 4σ, mínimo 20 mV/s

            % Hold time: tiempo que se mantiene la máscara activa después
            % de que el movimiento cae bajo el umbral.
            % FIX #5: 250 ms (era 400 ms en el código, 350 ms en el comentario).
            % 250 ms cubre el tiempo de asentamiento post-sacádico (oscilaciones
            % residuales ~50-80 ms) con margen sin comerse la siguiente fijación.
            holdSamp = round(0.250 * fs);   % FIX #5: 250 ms

            fprintf('  Deadband — ampTh: %.1f mV  velTh: %.1f mV/s  hold: 250 ms\n', ...
                ampTh, velTh);

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 3 — MÁSCARA DE MOVIMIENTO REAL
            % ══════════════════════════════════════════════════════════════════════
            % AND: ambos criterios (vel Y amp) deben cumplirse simultáneamente.
            isMovement = (velSmooth > velTh) & (abs(sig) > ampTh);

            % ── Hold hacia adelante (post-sacada) ─────────────────────────────────
            % Mantiene la máscara activa durante 250 ms después de que el
            % movimiento cae bajo los umbrales, cubriendo la cola de la sacada
            % y las oscilaciones post-sacádicas.
            mask      = false(N, 1);
            holdCount = 0;
            for i = 1:N
                if isMovement(i)
                    holdCount = holdSamp;
                end
                if holdCount > 0
                    mask(i)   = true;
                    holdCount = holdCount - 1;
                end
            end

            % ── FIX #6: Hold hacia atrás (pre-onset, 20 ms) ──────────────────────
            % La rampa de aceleración de una sacada empieza 10-20 ms antes de que
            % la velocidad cruce velTh. Sin esta extensión, el onset de la sacada
            % queda fuera de la máscara y se interpola como ruido, cortando el
            % inicio del evento y subestimando su amplitud.
            preHold = round(0.020 * fs);   % FIX #6: 20 ms hacia atrás
            idxMove = find(isMovement);
            for idx = idxMove'
                i1 = max(1, idx - preHold);
                mask(i1:idx) = true;
            end

            sig_out = sig;

            % ── Casos extremos ────────────────────────────────────────────────────
            if ~any(mask)
                % Ninguna zona supera los umbrales: señal plana o por debajo del
                % suelo de ruido adaptativo → zerofill (coherente con el propósito
                % del deadband: si no hay movimiento detectable, la señal no aporta)
                sig_out = zeros(N, 1);
                return
            end

            if all(mask)
                % Todo el registro se clasifica como movimiento: devolver sin cambios
                return
            end

            % ── Guard de interpolación ────────────────────────────────────────────
            % interp1 necesita mínimo 2 puntos de referencia para interpolar.
            if sum(mask) < 2
                sig_out = zeros(N, 1);
                return
            end

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 4 — INTERPOLACIÓN DE ZONAS DE RUIDO
            % ══════════════════════════════════════════════════════════════════════
            % FIX #2: La versión original usaba interp1(..., 'linear', 'extrap').
            % Con 'extrap', si hay un artefacto grande en el primer o último
            % segundo de la grabación (fuera del rango de puntos conocidos),
            % interp1 proyecta la tendencia lineal hasta el extremo y puede
            % generar valores de cientos de mV que el detector de sacadas
            % interpreta como eventos gigantes.
            %
            % Solución: separar los índices no enmascarados en dos grupos:
            %   · dentro  → entre el primer y último punto conocido → interpolar
            %   · fuera   → antes del primer / después del último punto → cero
            % El cero es seguro: el filtro de amplitud mínima en detectarEventos
            % los descartará de todas formas.

            idxAll  = (1:N)';
            idxKnow = idxAll(mask);
            valKnow = sig(mask);

            idx_noMask = idxAll(~mask);
            fuera  = idx_noMask < idxKnow(1) | idx_noMask > idxKnow(end);
            dentro = ~mask;
            dentro(~mask) = ~fuera;   % dentro = no enmascarado Y dentro del rango

            if any(dentro)
                sig_out(dentro) = interp1(idxKnow, valKnow, ...
                    idxAll(dentro), 'linear');
            end
            % Fuera del rango → cero (nunca 'extrap')
            sig_out(~mask & ~dentro) = 0;   % FIX #2: sin extrapolación
        end

        function TomarMedidasClicked(app)
            ResetSignals(app);
            ChangeWindowTo(app,app.GraficasScreen);
            value = app.ConectionCableCheckBox.Value;
            if value == 1
                DataConnectionCable(app);
                disp(app.Device);
            else
                DataConnection(app);
                disp(app.Device);
            end
        end

        function AccederClicked(app)
            app.usuario = app.UserNameHTML.Data.value;
            app.password = app.PasswordHTML.Data.value;
            disp(app.usuario);
            disp(app.password);
            pathToMLAPP = fileparts(which(class(app)));
            archivoJSON = fullfile(pathToMLAPP, 'usuarios.json');
            texto = fileread(archivoJSON);
            datos = jsondecode(texto);
            if ~isfile(archivoJSON)
                datos.usuarios = struct('username', {}, 'password', {});
            else
                datos = jsondecode(fileread(archivoJSON));
                if ~isfield(datos, 'usuarios')
                    datos.usuarios = struct('username', {}, 'password', {});
                end
            end
            usuarios = datos.usuarios;
            autenticado = false;
            if app.crearcuenta
                yaExiste = any(arrayfun(@(u) strcmp(u.username, app.usuario), usuarios));

                if yaExiste
                    app.SignInStateLBL.Text = "Ese usuario ya existe";
                    return;
                end

                nuevoUsuario.username = app.usuario;
                nuevoUsuario.password = app.password;
                datos.usuarios(end+1) = nuevoUsuario;


                fid = fopen(archivoJSON, 'w');
                fprintf(fid, '%s\n', jsonencode(datos));
                fclose(fid);
                app.UserNameHTML.Data = struct('clear', true);
                app.PasswordHTML.Data = struct('clear', true);
                ChangeWindowTo(app,app.ChooseActionScreen);
                return;
            end
            for i = 1:length(usuarios)
                u = usuarios(i);
                if strcmp(u.username, app.usuario) && strcmp(u.password, app.password)
                    autenticado = true;
                    app.SignInStateLBL.Text = "Sesion iniciada correctamente";
                    break;

                end
            end
            if autenticado
                app.UserNameHTML.Data = struct('clear', true);
                app.PasswordHTML.Data = struct('clear', true);
                ChangeWindowTo(app,app.ChooseActionScreen);
            else
                app.SignInStateLBL.Text = "Usuario o contraseña incorrecta";
            end

        end
        

        function ConectarGafasClicked(app)
            value = app.ConectionCableCheckBox.Value;
            if value == 1
                TomarMedidasClicked(app);
            else
                try
                    PrepareWifi(app);
                catch ME
                    uialert(app.UIFigure, ME.message, "Error WiFi");
                end
                ChangeWindowTo(app,app.ConectScreen);
                RefreshWifis(app);
            end
        end
        function StartStopClicked(app,tipo)
            if strcmp(tipo, 'Start')
                % Avisar si hay señal previa
                if ~isempty(app.SHEOL) || ~isempty(app.SVEOL) || ~isempty(app.SHEOR) || ~isempty(app.SVEOR)
                    try
                        flush(app.Device);
                    catch
                    end
                    selection = uiconfirm(app.UIFigure, ...
                        'Las señales tomadas seran borradas. ¿Quieres continuar?', ...
                        'Nueva adquisición', ...
                        'Options',       {'Continuar', 'Cancelar'}, ...
                        'DefaultOption', 'Cancelar', ...
                        'CancelOption',  'Cancelar', ...
                        'Icon',          'warning');

                    if strcmp(selection, 'Cancelar')
                        return
                    elseif strcmp(selection, 'Continuar')
                        ResetSignals(app);
                        return
                    end
                end
                % Solo reconectar si el dispositivo se perdió
                if isempty(app.Device)
                    if app.ConectionCableCheckBox.Value == 1
                        DataConnectionCable(app);
                    else
                        DataConnection(app);
                    end
                end
                DataAcquisition(app);
                app.StartStopHTML.Data = app.StopAppearance;
            elseif strcmp(tipo, 'Stop')
                StopAcquisition(app, 'manual');
                app.StartStopHTML.Data = app.StartAppearance;
            end
        end

        function VerAnalisisPreviosClicked(app)
            app.VerAnalisisPreviosHTML;
        end

        function AnalizarMedidasClicked(app)
            ChangeWindowTo(app,app.AnalisisSignalsScreen);
            PlotSignalsAcquired(app);
            calcularMetricasSacadas(app);
        end

        function ResultadosAnalisisClicked(app)
            if isempty(app.SaccadesMetrics)
                calcularMetricasSacadas(app);
            end

            ChangeWindowTo(app,app.AnalisisScreen);
        end
        function AnalisisEventosClicked(app)
            if isempty(app.SaccadesMetrics)
                calcularMetricasSacadas(app);
            end
            ChangeWindowTo(app,app.EventsAnalisisScreen);
        end
        function ExpandSignalView(app, signalName)
            app.ExpandedPlot.Title.String = signalName;

            if ~app.SignalsFiltered_OK
                ChangeWindowTo(app, app.ExpandedViewScreen);
                return
            end

            fs = app.fs_fixed;

            switch signalName
                case 'R Vertical +'
                    y = app.SVEOR_f;  color = app.ColorSVEOR; N = length(y);
                case 'R Horizontal +'
                    y = app.SHEOR_f;  color = app.ColorSHEOR; N = length(y);
                case 'L Vertical +'
                    y = app.SVEOL_f;  color = app.ColorSVEOL; N = length(y);
                case 'L Horizontal +'
                    y = app.SHEOL_f;  color = app.ColorSHEOL; N = length(y);
                otherwise
                    ChangeWindowTo(app, app.ExpandedViewScreen);
                    return
            end
            t  = (0:N-1) / fs;
            % Guardar señal completa para el scroll
            app.ExpandedSignalTime  = t;

            totalDur = t(end);
            winSize  = min(app.ExpandedViewWindow, totalDur);

            % Dibujar señal completa (solo una vez, luego solo se mueve XLim)
            cla(app.ExpandedPlot);
            plot(app.ExpandedPlot, t, y, 'Color' , color);
            xlabel(app.ExpandedPlot, 'Time (s)');
            ylabel(app.ExpandedPlot, 'mV');
            % Fijar YLim al rango global de TODA la señal
            yMin = min(y);
            yMax = max(y);
            margin = (yMax - yMin) * 0.1;   % 10% de margen arriba y abajo
            if margin == 0
                margin = 1;                  % evitar YLim plano si la señal es constante
            end
            app.ExpandedPlot.YLim     = [yMin - margin, yMax + margin];
            app.ExpandedPlot.YLimMode = 'manual';
            app.ExpandedPlot.XLimMode = 'manual';

            if totalDur > winSize
                app.ExpandedPlot.XLim = [0, winSize];

                app.SignalViewSlider.Limits = [0, totalDur - winSize];
                app.SignalViewSlider.Value  = 0;
                app.SignalViewSlider.MajorTicks = [];
                app.SignalViewSlider.MinorTicks = [];
                app.SignalViewSlider.Visible = 'on';
            else
                % Señal corta, cabe entera
                app.ExpandedPlot.XLim = [0, max(totalDur, 0.1)];
                app.SignalViewSlider.Visible = 'off';
            end

            ChangeWindowTo(app, app.ExpandedViewScreen);
        end

        function BackEventsAnalisisClicked(app)
            ChangeWindowTo(app,app.AnalisisScreen);
        end
        function BackConectClicked(app)
            ChangeWindowTo(app,app.ChooseActionScreen);
        end

        function BackChooseActionClicked(app)
            ChangeWindowTo(app,app.MainScreen);
        end

        function BackAnalisisClicked(app)
            ChangeWindowTo(app,app.AnalisisSignalsScreen);
        end

        function BackGraficasClicked(app)
            selection = uiconfirm(app.UIFigure, ...
                'Se va a desconectar del dispositivo actual ¿Quiere seguir con la operación?', ...
                'Confirmar Desconexión', ...
                'Options',       {'OK', 'Cancel'}, ...
                'DefaultOption', 'OK', ...
                'CancelOption',  'Cancel', ...
                'Icon',          'warning');
            switch selection
                case 'OK'
                    StartStopClicked(app,'Stop');
                    ResetSignals(app);
                    ChangeWindowTo(app,app.ChooseActionScreen);
                case 'Cancel'
                    return
            end
        end
        function BackExpandedViewClicked(app)
            ChangeWindowTo(app,app.AnalisisSignalsScreen);
        end
        function BackAnalisisSignalsClicked(app)
            selection = uiconfirm(app.UIFigure, ...
                'Las señales tomadas no guardadas seran borradas. ¿Quieres continuar?', ...
                'Nueva adquisición', ...
                'Options',       {'Continuar sin guardar', 'Guardar y continuar', 'Cancelar'}, ...
                'DefaultOption', 'Guardar y continuar', ...
                'CancelOption',  'Cancelar', ...
                'Icon',          'question');
            switch selection
                case 'Guardar y continuar'
                    saved = saveSignals(app);
                    if ~saved
                        return  % user cancelled file dialog, abort close
                    end
                case 'Cancelar'
                    return
            end
            ResetSignals(app);
            ChangeWindowTo(app,app.GraficasScreen);
        end

        function PlotSignalsAcquired(app)
            if ~app.SignalsFiltered_OK
                return
            end
            fs = app.fs_fixed;
            N  = length(app.SHEOR_f);
            t  = (0:N-1) / fs;

            pad = t(end) * 0.01;

            plot(app.RHorizontalAxis, t, app.SHEOR_f, 'Color', app.ColorSHEOR);
            xlabel(app.RHorizontalAxis, 'Time (s)'); ylabel(app.RHorizontalAxis, 'mV');
            app.RHorizontalAxis.XLim = [t(1) - pad, t(end) + pad];
            app.RHorizontalAxis.XLimMode = 'manual';

            plot(app.RVerticalAxis, t, app.SVEOR_f, 'Color', app.ColorSVEOR);
            xlabel(app.RVerticalAxis, 'Time (s)'); ylabel(app.RVerticalAxis, 'mV');
            app.RVerticalAxis.XLim = [t(1) - pad, t(end) + pad];
            app.RVerticalAxis.XLimMode = 'manual';

            plot(app.LHorizontalAxis, t, app.SHEOL_f, 'Color', app.ColorSHEOL);
            xlabel(app.LHorizontalAxis, 'Time (s)'); ylabel(app.LHorizontalAxis, 'mV');
            app.LHorizontalAxis.XLim = [t(1) - pad, t(end) + pad];
            app.LHorizontalAxis.XLimMode = 'manual';

            plot(app.LVerticalAxis, t, app.SVEOL_f, 'Color', app.ColorSVEOL);
            xlabel(app.LVerticalAxis, 'Time (s)'); ylabel(app.LVerticalAxis, 'mV');
            app.LVerticalAxis.XLim = [t(1) - pad, t(end) + pad];
            app.LVerticalAxis.XLimMode = 'manual';
        end

        function calcularMetricasSacadas(app)
            % CALCULARMETRICASSACADAS
            % Coordina la detección de eventos oculares usando las señales filtradas.

            if ~app.SignalsFiltered_OK
                uialert(app.UIFigure, ...
                    'Primero debes adquirir y filtrar señales.', ...
                    'Sin señales filtradas');
                return
            end

            if isempty(app.fs_fixed) || app.fs_fixed <= 0
                uialert(app.UIFigure, ...
                    'No hay una frecuencia de muestreo válida.', ...
                    'Error fs');
                return
            end

            % ── Señales globales ──────────────────────────────────────────────────
            % HEOG: diferencial horizontal (derecha − izquierda) / 2
            % VEOG: media vertical (suma / 2, ambos canales del mismo sentido)
            app.HEOG_global = (app.SHEOR_f - app.SHEOL_f) / 2;
            app.VEOG_global = (app.SVEOR_f + app.SVEOL_f) / 2;

            % ── Detección ─────────────────────────────────────────────────────────
            app.SaccadesMetrics = detectarEventosOculares(app, ...
                app.HEOG_global, app.VEOG_global, app.fs_fixed);

            % ── Actualizar UI ─────────────────────────────────────────────────────
            actualizarResultadosSacadas(app);
            actualizarTablaEventosSacadas(app);
        end


        % ─────────────────────────────────────────────────────────────────────────
        %  detectarEventosOculares
        %  Versión corregida — 10 bugs arreglados respecto a la versión original
        % ─────────────────────────────────────────────────────────────────────────
        function metrics = detectarEventosOculares(~, heog, veog, fs)
            % DETECTAREVENTOSOCULARES
            % Algoritmo clínico de detección para EOG:
            %   · Sacadas    — umbral de velocidad dual (detección + extensión onset/offset)
            %   · Parpadeos  — amplitud + velocidad en VEOG (criterio AND)
            %   · Fijaciones — intervalos inter-evento
            %   · Main sequence — correlación pico-vel vs amplitud (Bahill 1975)
            %
            % Entradas:
            %   heog  — señal HEOG global filtrada (mV), columna
            %   veog  — señal VEOG global filtrada (mV), columna
            %   fs    — frecuencia de muestreo (Hz)
            %
            % Salida:
            %   metrics — struct con todos los resultados y tablas de eventos
            %
            % BUGS CORREGIDOS vs versión anterior:
            %   #2  [false;...;false] en fijaciones (era [true;...;true])
            %   #3  AND en parpadeos en lugar de OR
            %   #4  Amplitud de sacada con ventana 15 ms, no puntos únicos
            %   #8  MAD de umbrales calculado solo en zonas de quietud
            %   #9  Duración mínima parpadeo: 60 ms (era 100 ms)
            %   #10 Ventana onset/offset: 85 ms (era 50 ms)
            %   #11 merge_gap: 12 ms (era 30 ms)
            %   #12 amp_min adaptativo (era 20 mV fijo)
            %   #13 main sequence con validación de rango y n mínimo
            %   #14 gradient() para velocidad (era [0; diff(...)])

            heog = double(heog(:));
            veog = double(veog(:));
            N    = length(heog);
            t    = (0:N-1)' / fs;

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 1 — ESTIMACIÓN DE VELOCIDAD
            % ══════════════════════════════════════════════════════════════════════
            % FIX #14: gradient() usa diferencias centrales simétricas en todos los
            % puntos incluyendo extremos. [0; diff(...)] introducía un cero artificial
            % en el sample 1 que ocultaba sacadas al inicio de la grabación.

            sgWin_ms = 0.020;
            sgWin    = 2 * floor(fs * sgWin_ms / 2) + 1;
            sgWin    = max(sgWin, 5);
            sgOrder  = 3;

            vel_h    = gradient(sgolayfilt(heog, sgOrder, sgWin)) * fs;
            vel_v    = gradient(sgolayfilt(veog, sgOrder, sgWin)) * fs;
            velH_abs = abs(vel_h);
            velV_abs = abs(vel_v);

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 2 — UMBRALES ADAPTATIVOS (MAD sobre zonas de quietud)
            % ══════════════════════════════════════════════════════════════════════
            % FIX #8: En la versión original el MAD se calculaba sobre toda la señal,
            % incluyendo las propias sacadas. Eso sube la mediana artificialmente y
            % hace los umbrales más restrictivos, perdiendo sacadas pequeñas.
            % Solución: primera pasada rough para aislar zonas sin sacadas, luego
            % calcular MAD solo en esas zonas (método Engbert-Mergenthaler 2006).

            th_rough_h  = median(velH_abs) + 6 * median(abs(velH_abs - median(velH_abs)));
            quiet_mask_h = velH_abs < th_rough_h;
            if sum(quiet_mask_h) < 10
                quiet_mask_h = true(size(velH_abs));  % fallback si señal muy activa
            end
            med_h  = median(velH_abs(quiet_mask_h));
            mad_h  = median(abs(velH_abs(quiet_mask_h) - med_h));

            T_high = max(med_h + 6 * mad_h, 40);   % mínimo 40 mV/s
            T_low  = max(med_h + 3 * mad_h, 20);   % mínimo 20 mV/s

            fprintf('Umbrales velocidad — T_high: %.1f  T_low: %.1f  mV/s\n', ...
                T_high, T_low);

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 3 — DETECCIÓN DE PARPADEOS (desde VEOG)
            % ══════════════════════════════════════════════════════════════════════
            % FIX #8 (aplicado a VEOG): MAD también sobre zonas tranquilas verticales.
            % FIX #3: Criterio AND en lugar de OR. Con OR, una sacada vertical grande
            %   dispara el criterio de amplitud y se clasifica como parpadeo, inflando
            %   el conteo. Con AND se exigen velocidad alta Y amplitud alta a la vez.
            % FIX #9: Duración mínima 60 ms en lugar de 100 ms. Parpadeos espontáneos
            %   en sujetos jóvenes duran 60-80 ms; con 100 ms se perdían hasta el 40%.

            th_rough_v   = median(velV_abs) + 6 * median(abs(velV_abs - median(velV_abs)));
            quiet_mask_v = velV_abs < th_rough_v;
            if sum(quiet_mask_v) < 10
                quiet_mask_v = true(size(velV_abs));
            end
            med_va = median(velV_abs(quiet_mask_v));
            mad_va = median(abs(velV_abs(quiet_mask_v) - med_va));
            T_bv   = max(med_va + 5 * mad_va, 60);     % vel. parpadeo (mV/s)

            med_va2  = median(abs(veog));
            mad_va2  = median(abs(abs(veog) - med_va2));
            T_bamp   = max(med_va2 + 4 * mad_va2, 80); % amp. parpadeo (mV)

            % AND: ambos criterios deben cumplirse simultáneamente
            rawBlink = (velV_abs > T_bv) & (abs(veog) > T_bamp);

            d_b  = diff([0; rawBlink; 0]);
            bIni = find(d_b ==  1);
            bFin = find(d_b == -1) - 1;

            bDur_samp      = bFin - bIni + 1;
            blink_min_samp = round(0.060 * fs);   % FIX #9: 60 ms (era 100 ms)
            blink_max_samp = round(0.600 * fs);
            keepB = (bDur_samp >= blink_min_samp) & (bDur_samp <= blink_max_samp);
            bIni  = bIni(keepB);
            bFin  = bFin(keepB);

            % Máscara de parpadeo extendida ±50 ms
            blinkHold = round(0.050 * fs);
            blinkMask = false(N, 1);
            for i = 1:length(bIni)
                i1 = max(1, bIni(i) - blinkHold);
                i2 = min(N, bFin(i) + blinkHold);
                blinkMask(i1:i2) = true;
            end

            fprintf('Parpadeos detectados: %d\n', length(bIni));

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 4 — CANDIDATOS A SACADA (HEOG, sin regiones de parpadeo)
            % ══════════════════════════════════════════════════════════════════════
            velH_clean = velH_abs;
            velH_clean(blinkMask) = 0;

            isSacc_high = velH_clean > T_high;

            d_s  = diff([0; isSacc_high; 0]);
            cIni = find(d_s ==  1);
            cFin = find(d_s == -1) - 1;

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 5 — REFINAMIENTO DE ONSET / OFFSET
            % ══════════════════════════════════════════════════════════════════════
            % FIX #10: Ventana de búsqueda ampliada a 85 ms (era 50 ms).
            % En sacadas grandes (>20°) la rampa de aceleración empieza hasta 80 ms
            % antes de superar T_high. Con 50 ms, el onset se detectaba tarde,
            % la duración quedaba subestimada y la amplitud era menor que la real.

            ext_samp = round(0.085 * fs);   % FIX #10: 85 ms (era 50 ms)
            nCand    = length(cIni);
            sIni     = zeros(nCand, 1);
            sFin     = zeros(nCand, 1);

            for k = 1:nCand
                % ── Onset: buscar hacia atrás hasta que vel < T_low ──────────────
                i_start = max(1, cIni(k) - ext_samp);
                pre_seg = velH_clean(i_start:cIni(k));
                below   = find(pre_seg < T_low, 1, 'last');
                if isempty(below)
                    sIni(k) = i_start;
                else
                    sIni(k) = i_start + below - 1;
                end

                % ── Offset: buscar hacia adelante hasta que vel < T_low ──────────
                i_end    = min(N, cFin(k) + ext_samp);
                post_seg = velH_clean(cFin(k):i_end);
                below    = find(post_seg < T_low, 1, 'first');
                if isempty(below)
                    sFin(k) = i_end;
                else
                    sFin(k) = cFin(k) + below - 2;
                end
                sFin(k) = max(sFin(k), cFin(k));   % no retroceder antes del pico
            end

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 6 — FUSIÓN DE CANDIDATOS PRÓXIMOS
            % ══════════════════════════════════════════════════════════════════════
            % FIX #11: merge_gap reducido a 12 ms (era 30 ms).
            % Con 30 ms se fusionaban sacadas en ráfaga (saccadic burst) que son
            % eventos neurológicamente distintos con ISI de 20-80 ms. Con 12 ms
            % solo se fusionan fragmentos del mismo evento real.

            merge_gap = round(0.012 * fs);   % FIX #11: 12 ms (era 30 ms)

            if ~isempty(sIni)
                m_ini    = sIni(1);
                m_fin    = sFin(1);
                mIniList = zeros(nCand, 1);
                mFinList = zeros(nCand, 1);
                nMerged  = 0;

                for k = 2:nCand
                    if sIni(k) - m_fin <= merge_gap
                        m_fin = max(m_fin, sFin(k));
                    else
                        nMerged = nMerged + 1;
                        mIniList(nMerged) = m_ini;
                        mFinList(nMerged) = m_fin;
                        m_ini = sIni(k);
                        m_fin = sFin(k);
                    end
                end
                nMerged = nMerged + 1;
                mIniList(nMerged) = m_ini;
                mFinList(nMerged) = m_fin;

                sIni = mIniList(1:nMerged);
                sFin = mFinList(1:nMerged);
            end

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 7 — VALIDACIÓN FISIOLÓGICA
            % ══════════════════════════════════════════════════════════════════════
            dur_min = round(0.010 * fs);
            dur_max = round(0.150 * fs);
            isi_min = round(0.080 * fs);   % 80 ms período refractario

            % FIX #12: amp_min adaptativo basado en MAD del HEOG.
            % 20 mV fijo rechazaba sacadas reales en sujetos con mala impedancia de
            % electrodo donde la señal es más débil. El MAD escala con la calidad
            % real de la señal de cada sesión. 1.4826 convierte MAD a σ estimada.
            heog_mad = median(abs(heog - median(heog)));
            amp_min  = max(heog_mad * 1.4826 * 2, 12);  % 2σ, mínimo 12 mV

            % Ventana para calcular amplitud de forma robusta (FIX #4)
            win_s = max(round(0.015 * fs), 3);   % ventana 15 ms

            % ── Filtro duración ───────────────────────────────────────────────────
            durSamp = sFin - sIni + 1;
            keep    = (durSamp >= dur_min) & (durSamp <= dur_max);
            sIni    = sIni(keep);
            sFin    = sFin(keep);

            % ── Filtro amplitud ───────────────────────────────────────────────────
            % FIX #4: La amplitud se calcula como diferencia entre la media de una
            % ventana de 15 ms alrededor del onset y del offset, no como diferencia
            % entre dos samples únicos que pueden estar en picos de ruido transitorio.
            keepAmp = false(size(sIni));
            for k = 1:length(sIni)
                i1      = sIni(k);
                i2      = sFin(k);
                amp_on  = mean(heog(max(1, i1 - win_s) : i1));
                amp_off = mean(heog(i2 : min(N, i2 + win_s)));
                if abs(amp_off - amp_on) >= amp_min
                    keepAmp(k) = true;
                end
            end
            sIni = sIni(keepAmp);
            sFin = sFin(keepAmp);

            % ── Filtro ISI ────────────────────────────────────────────────────────
            % Guard añadido: si k-1 ya fue eliminado en una iteración anterior,
            % no comparar con él (la versión original podía marcar k como falso
            % comparándolo con una sacada ya descartada).
            if length(sIni) > 1
                keepISI = true(size(sIni));
                for k = 2:length(sIni)
                    if ~keepISI(k-1)
                        continue   % k-1 ya eliminado, no comparar
                    end
                    if sIni(k) - sFin(k-1) < isi_min
                        vPrev = max(velH_clean(sIni(k-1):sFin(k-1)));
                        vCurr = max(velH_clean(sIni(k):sFin(k)));
                        if vPrev >= vCurr
                            keepISI(k)   = false;
                        else
                            keepISI(k-1) = false;
                        end
                    end
                end
                sIni = sIni(keepISI);
                sFin = sFin(keepISI);
            end

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 8 — EXTRACCIÓN DE CARACTERÍSTICAS POR SACADA
            % ══════════════════════════════════════════════════════════════════════
            nSac      = length(sIni);
            onsetIdx  = sIni;
            offsetIdx = sFin;
            peakIdx   = zeros(nSac, 1);

            onsetTime  = t(sIni);
            offsetTime = t(min(sFin, N));
            durMs      = (sFin - sIni + 1) / fs * 1000;
            amplitude  = zeros(nSac, 1);
            peakVel    = zeros(nSac, 1);
            direction  = strings(nSac, 1);

            for k = 1:nSac
                % Pico de velocidad
                seg_v = velH_clean(sIni(k):sFin(k));
                [pv, pvi] = max(seg_v);
                peakIdx(k) = sIni(k) + pvi - 1;
                peakVel(k) = pv;

                % FIX #4: amplitud con ventana de 15 ms en onset y offset.
                % Promediar 15 ms alrededor de cada extremo da una estimación
                % estable del nivel real de la señal, inmune a picos de ruido.
                i1      = sIni(k);
                i2      = sFin(k);
                amp_on  = mean(heog(max(1, i1 - win_s) : i1));
                amp_off = mean(heog(i2 : min(N, i2 + win_s)));
                amplitude(k) = amp_off - amp_on;

                if amplitude(k) > 0
                    direction(k) = "derecha";
                else
                    direction(k) = "izquierda";
                end
            end

            if nSac > 0
                peakTime = t(peakIdx);
            else
                peakTime = zeros(0, 1);
            end

            fprintf('Sacadas validadas: %d\n', nSac);

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 9 — CARACTERÍSTICAS DE PARPADEOS
            % ══════════════════════════════════════════════════════════════════════
            nBlink      = length(bIni);
            blinkOnset  = zeros(nBlink, 1);
            blinkOffset = zeros(nBlink, 1);
            blinkDurMs  = zeros(nBlink, 1);
            blinkAmp    = zeros(nBlink, 1);

            for k = 1:nBlink
                blinkOnset(k)  = t(bIni(k));
                bFinK          = min(bFin(k), N);
                blinkOffset(k) = t(bFinK);
                blinkDurMs(k)  = (bFinK - bIni(k) + 1) / fs * 1000;
                blinkAmp(k)    = max(abs(veog(bIni(k):bFinK)));
            end

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 10 — DETECCIÓN DE FIJACIONES
            % ══════════════════════════════════════════════════════════════════════
            % FIX #2: [false;~eventMask;false] en lugar de [true;~eventMask;true].
            % Con el borde true, diff generaba -1 en el primer sample aunque fuera
            % una fijación real, ocultando la primera fijación y desalineando todos
            % los índices siguientes. Con false los bordes de subida (+1) son los
            % onsets reales y los de bajada (-1) son los offsets reales.

            eventMask = blinkMask;
            for k = 1:nSac
                eventMask(sIni(k):sFin(k)) = true;
            end

            d_fix  = diff([false; ~eventMask; false]);  % FIX #2
            fixIni = find(d_fix ==  1);                 % onset fijación
            fixFin = find(d_fix == -1) - 1;             % offset fijación

            fix_min_samp = round(0.100 * fs);
            fixDur_samp  = fixFin - fixIni + 1;
            keepFix      = fixDur_samp >= fix_min_samp;
            fixIni = fixIni(keepFix);
            fixFin = fixFin(keepFix);

            nFix      = length(fixIni);
            fixDurMs  = (fixFin - fixIni + 1) / fs * 1000;
            fixOnset  = t(fixIni);
            fixOffset = t(min(fixFin, N));

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 11 — MAIN SEQUENCE (Bahill et al., 1975)
            % ══════════════════════════════════════════════════════════════════════
            % FIX #13: Mínimo 7 sacadas en lugar de 3.
            %   Con n=3, Spearman puede ser ±1.0 por simple coincidencia de
            %   ordenación. Con n≥7 el IC al 95% empieza a ser interpretable.
            % FIX #13: Validar rango de amplitudes explorado.
            %   Si todas las sacadas tienen amplitudes similares, r será bajo
            %   aunque el sistema oculomotor sea completamente normal. La main
            %   sequence solo tiene sentido clínico si el rango cubre ≥40 mV.

            mainSeq_r     = NaN;
            mainSeq_p     = NaN;
            mainSeq_slope = NaN;
            range_amp_mV  = 0;

            MIN_SAC_CORR = 7;   % FIX #13: era 3

            if nSac >= MIN_SAC_CORR
                amp_abs      = abs(amplitude);
                range_amp_mV = max(amp_abs) - min(amp_abs);

                [mainSeq_r, mainSeq_p] = corr(amp_abs, peakVel, 'type', 'Spearman');

                validMS = amp_abs > 0 & peakVel > 0;
                if sum(validMS) >= MIN_SAC_CORR
                    p_ms = polyfit(log(amp_abs(validMS)), log(peakVel(validMS)), 1);
                    mainSeq_slope = p_ms(1);
                end
            end

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 12 — MÉTRICAS GLOBALES
            % ══════════════════════════════════════════════════════════════════════
            totalTime = N / fs;

            idxRight = direction == "derecha";
            idxLeft  = direction == "izquierda";
            nRight   = sum(idxRight);
            nLeft    = sum(idxLeft);

            freq_total = nSac / totalTime;
            freq_min   = freq_total * 60;

            % Guard explícito para nSac=0: mean de array vacío devuelve NaN
            if nSac > 0
                mean_vel   = mean(peakVel,             'omitnan');
                mean_vel_r = mean(peakVel(idxRight),   'omitnan');
                mean_vel_l = mean(peakVel(idxLeft),    'omitnan');
                mean_dur   = mean(durMs,               'omitnan');
                mean_amp   = mean(abs(amplitude),      'omitnan');
            else
                mean_vel = 0; mean_vel_r = 0; mean_vel_l = 0;
                mean_dur = 0; mean_amp   = 0;
            end
            if isnan(mean_vel_r), mean_vel_r = 0; end
            if isnan(mean_vel_l), mean_vel_l = 0; end

            mean_fix = mean(fixDurMs, 'omitnan');
            if isnan(mean_fix), mean_fix = 0; end

            asymmetry  = (nRight - nLeft) / max(nRight + nLeft, 1);
            blink_rate = nBlink / totalTime * 60;

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 13 — TABLAS DE EVENTOS
            % ══════════════════════════════════════════════════════════════════════
            if nSac > 0
                eventsTable = table( ...
                    onsetIdx,  offsetIdx,  peakIdx,   ...
                    onsetTime, offsetTime, peakTime,  ...
                    durMs,     amplitude,  peakVel,   direction, ...
                    'VariableNames', { ...
                    'onsetIdx','offsetIdx','peakIdx', ...
                    'onsetTime','offsetTime','peakTime', ...
                    'duration_ms','amplitude_mV','peakVel_mVs','direction'});
            else
                eventsTable = table();
            end

            if nBlink > 0
                blinksTable = table( ...
                    bIni(:), bFin(:), blinkOnset, blinkOffset, blinkDurMs, blinkAmp, ...
                    'VariableNames', { ...
                    'onsetIdx','offsetIdx','onsetTime','offsetTime', ...
                    'duration_ms','amplitude_mV'});
            else
                blinksTable = table();
            end

            if nFix > 0
                fixTable = table( ...
                    fixIni, fixFin, fixOnset, fixOffset, fixDurMs, ...
                    'VariableNames', { ...
                    'onsetIdx','offsetIdx','onsetTime','offsetTime','duration_ms'});
            else
                fixTable = table();
            end

            % ══════════════════════════════════════════════════════════════════════
            %  PASO 14 — EMPAQUETAR RESULTADO
            % ══════════════════════════════════════════════════════════════════════
            metrics = struct();

            metrics.eventsTable   = eventsTable;
            metrics.blinksTable   = blinksTable;
            metrics.fixTable      = fixTable;

            metrics.duration      = durMs;
            metrics.amplitude     = amplitude;
            metrics.peakVel       = peakVel;
            metrics.direction     = direction;

            metrics.num_total     = nSac;
            metrics.num_right     = nRight;
            metrics.num_left      = nLeft;
            metrics.num_blinks    = nBlink;
            metrics.num_fix       = nFix;

            metrics.frequency_total = freq_total;
            metrics.frequency_min   = freq_min;
            metrics.blink_rate      = blink_rate;

            metrics.mean_velocity   = mean_vel;
            metrics.mean_velocity_r = mean_vel_r;
            metrics.mean_velocity_l = mean_vel_l;
            metrics.mean_duration   = mean_dur;
            metrics.mean_amplitude  = mean_amp;
            metrics.mean_fixation   = mean_fix;

            metrics.asymmetry_num   = asymmetry;
            metrics.mainSeq_r       = mainSeq_r;
            metrics.mainSeq_p       = mainSeq_p;
            metrics.mainSeq_slope   = mainSeq_slope;
            metrics.range_amp_mV    = range_amp_mV;  % nuevo: para validar interpretación
            metrics.threshold_vel   = T_high;
            metrics.amp_min_used    = amp_min;        % nuevo: útil para debugging
        end

        function actualizarResultadosSacadas(app)

            if isempty(app.SaccadesMetrics)
                return
            end

            m = app.SaccadesMetrics;

            % ── Paleta de colores ────────────────────────────────────────────────
            C_GREEN  = [0.16  0.73  0.51];
            C_ORANGE = [0.95  0.45  0.09];
            C_RED    = [0.85  0.10  0.15];
            C_PURPLE = [0.36  0.07  0.80];
            C_CYAN   = [0.00  0.71  0.85];
            C_MUTED  = [0.55  0.55  0.60];
            BG_LAVANDA = [0.902 0.902 0.9804];

            % ════════════════════════════════════════════════════════════════════
            %  1. HEADER — Fecha · Duración · Frecuencia
            % ════════════════════════════════════════════════════════════════════
            app.TimeLBL.Text      = datestr(now, 'dd/mm/yyyy  HH:MM');
            app.DurationLBL.Text  = sprintf('%.0f s', length(app.SHEOR_f) / app.fs_fixed);
            app.FrequencyLBL.Text = sprintf('%.0f Hz', app.fs_fixed);

            % ════════════════════════════════════════════════════════════════════
            %  2. GAUGE — Tasa de parpadeo
            % ════════════════════════════════════════════════════════════════════
            app.Gauge.Value = min(max(m.blink_rate, 0), 40);

            % Label8 → valor numérico grande
            app.RateParpadeosLBL.Text     = sprintf('%.1f', m.blink_rate);
            app.RateParpadeosLBL.FontSize = 20;
            app.RateParpadeosLBL.FontWeight = 'bold';

            % Label9 → total de parpadeos
            app.TotalParpadeosLBL.Text = sprintf('%d total', m.num_blinks);

            if m.blink_rate < 8
                app.RateParpadeosLBL.FontColor = C_ORANGE;          % fatiga
            elseif m.blink_rate > 25
                app.RateParpadeosLBL.FontColor = C_RED;             % irritación
            else
                app.RateParpadeosLBL.FontColor = C_GREEN;           % normal
            end

            % ════════════════════════════════════════════════════════════════════
            %  3. SCATTER MAIN SEQUENCE — UIAxes
            % ════════════════════════════════════════════════════════════════════
            cla(app.CorrAxes);
            hold(app.CorrAxes, 'on');

            if m.num_total > 0 && ~isempty(m.eventsTable)
                T    = m.eventsTable;
                idxR = strcmp(string(T.direction), 'derecha');
                idxL = strcmp(string(T.direction), 'izquierda');

                % Puntos por dirección
                if any(idxR)
                    scatter(app.CorrAxes, abs(T.amplitude_mV(idxR)), T.peakVel_mVs(idxR), ...
                        22, C_CYAN, 'filled', 'MarkerFaceAlpha', 0.85);
                end
                if any(idxL)
                    scatter(app.CorrAxes, abs(T.amplitude_mV(idxL)), T.peakVel_mVs(idxL), ...
                        22, C_PURPLE, 'filled', 'MarkerFaceAlpha', 0.85);
                end

                % Línea de tendencia
                amp_all = abs(T.amplitude_mV);
                vel_all = T.peakVel_mVs;
                validMS = amp_all > 0 & vel_all > 0;
                if sum(validMS) >= 3
                    p  = polyfit(amp_all(validMS), vel_all(validMS), 1);
                    xf = linspace(min(amp_all(validMS)), max(amp_all(validMS)), 60);
                    plot(app.CorrAxes, xf, polyval(p, xf), '--', ...
                        'Color', [0.7 0.7 0.75], 'LineWidth', 1.2);
                end
            end

            hold(app.CorrAxes, 'off');
            app.CorrAxes.FontSize  = 8;
            app.CorrAxes.XColor    = C_MUTED;
            app.CorrAxes.YColor    = C_MUTED;
            app.CorrAxes.Color     = [1 1 1];
            app.CorrAxes.Box       = 'off';
            grid(app.CorrAxes, 'on');
            app.CorrAxes.GridColor = [0.90 0.90 0.93];

            % Etiqueta R encima del scatter
            if ~isnan(m.mainSeq_r)
                if m.mainSeq_r >= 0.70
                    rTxt = sprintf('r = %.2f  Normal',    m.mainSeq_r);
                    rCol = C_GREEN;
                elseif m.mainSeq_r >= 0.40
                    rTxt = sprintf('r = %.2f  Moderado',  m.mainSeq_r);
                    rCol = C_ORANGE;
                else
                    rTxt = sprintf('r = %.2f  Alterado',  m.mainSeq_r);
                    rCol = C_RED;
                end
                app.RresultLBL.Text      = rTxt;
                app.RresultLBL.FontColor = rCol;
                app.RresultLBL.FontWeight = 'bold';
            else
                app.RresultLBL.Text      = 'Sin datos suficientes  (n < 3)';
                app.RresultLBL.FontColor = C_MUTED;
            end

            % ════════════════════════════════════════════════════════════════════
            %  4. ASIMETRÍA — UIAxes2
            % ═══════════════════════════════════════════════════════════════════
            cla(app.AsimetriaAxes);
            hold(app.AsimetriaAxes, 'on');

            val = m.asymmetry_num;

            % Zonas de color de fondo
            patch(app.AsimetriaAxes, [-1.1 -0.25 -0.25 -1.1], [-1 -1 1 1], ...
                [1.0  0.93 0.88], 'EdgeColor', 'none', 'FaceAlpha', 0.8);
            patch(app.AsimetriaAxes, [-0.25 0.25 0.25 -0.25], [-1 -1 1 1], ...
                [0.88 1.0  0.92], 'EdgeColor', 'none', 'FaceAlpha', 0.8);
            patch(app.AsimetriaAxes, [0.25 1.1 1.1 0.25], [-1 -1 1 1], ...
                [1.0  0.93 0.88], 'EdgeColor', 'none', 'FaceAlpha', 0.8);

            % Líneas de referencia
            xline(app.AsimetriaAxes,  0,    '-',  'Color', [0.65 0.65 0.70], 'LineWidth', 0.8);
            xline(app.AsimetriaAxes,  0.25, '--', 'Color', [0.75 0.75 0.80], 'LineWidth', 0.7);
            xline(app.AsimetriaAxes, -0.25, '--', 'Color', [0.75 0.75 0.80], 'LineWidth', 0.7);

            % Barra de valor
            if abs(val) > 0.25
                barCol = C_ORANGE;
            else
                barCol = C_GREEN;
            end
            barh(app.AsimetriaAxes, 0, val, 0.65, 'FaceColor', barCol, 'EdgeColor', 'none');
            plot(app.AsimetriaAxes, val, 0.48, 'v', 'MarkerSize', 7, ...
                'MarkerFaceColor', barCol, 'MarkerEdgeColor', 'none');

            % Valor numérico
            text(app.AsimetriaAxes, val, 0.76, sprintf('%+.2f', val), ...
                'HorizontalAlignment', 'center', 'FontSize', 10, ...
                'FontWeight', 'bold', 'Color', barCol);

            % Conteos L/D
            text(app.AsimetriaAxes, -0.62, -0.72, sprintf('I: %d', m.num_left), ...
                'FontSize', 14, 'FontWeight', 'bold', ...
                'Color', C_PURPLE, 'HorizontalAlignment', 'center');
            text(app.AsimetriaAxes,  0.62, -0.72, sprintf('D: %d', m.num_right), ...
                'FontSize', 14, 'FontWeight', 'bold', ...
                'Color', C_CYAN, 'HorizontalAlignment', 'center');

            hold(app.AsimetriaAxes, 'off');
            app.AsimetriaAxes.XLim      = [-1.1 1.1];
            app.AsimetriaAxes.YLim      = [-1   1];
            app.AsimetriaAxes.YColor    = 'none';
            app.AsimetriaAxes.YTick     = [];
            app.AsimetriaAxes.XTick     = [-1 -0.25 0 0.25 1];
            app.AsimetriaAxes.XTickLabel = {'-1','−.25','0','+.25','+1'};
            app.AsimetriaAxes.FontSize  = 8;
            app.AsimetriaAxes.XColor    = C_MUTED;
            app.AsimetriaAxes.Color     = [1 1 1];
            app.AsimetriaAxes.Box       = 'off';

            % ════════════════════════════════════════════════════════════════════
            %  5. BOTONES DE MÉTRICAS CLAVE
            % ════════════════════════════════════════════════════════════════════
            % Color fijo identificativo por botón — independiente del valor

            C1 = [0.50 0.38 0.75];   % violeta suave  — Sacadas totales
            C2 = [0.24 0.60 0.47];   % salvia         — Duracion media
            C3 = [0.78 0.47 0.27];   % terracota      — Parpadeos
            C4 = [0.29 0.53 0.77];   % azul           — Fijacion media
            C5 = [0.60 0.41 0.63];   % malva          — Asimetria
            C6 = [0.23 0.57 0.66];   % teal           — Correlacion

            setMetricButton(app, app.SacadastotalesButton, ...
                'Sacadas totales', sprintf('%d', m.num_total), C1, BG_LAVANDA);

            setMetricButton(app, app.DurationmediaButton, ...
                'Duracion media', sprintf('%.0f ms', m.mean_duration), C2, BG_LAVANDA);

            setMetricButton(app, app.ParpadeosButton, ...
                'Parpadeos', sprintf('%d  (%.1f/min)', m.num_blinks, m.blink_rate), C3, BG_LAVANDA);

            setMetricButton(app, app.FijacionMediaButton, ...
                'Fijacion media', sprintf('%.0f ms', m.mean_fixation), C4, BG_LAVANDA);

            setMetricButton(app, app.AsimetriaButton, ...
                'Asimetria D/I', sprintf('%+.2f', m.asymmetry_num), C5, BG_LAVANDA);

            if ~isnan(m.mainSeq_r)
                corrTxt = sprintf('r = %.2f', m.mainSeq_r);
            else
                corrTxt = 'r = —';
            end
            setMetricButton(app, app.CorrelationButton, 'Correlacion', corrTxt, C6, BG_LAVANDA);

            % ════════════════════════════════════════════════════════════════════
            %  6. OBSERVACIONES — ObsHTML
            % ════════════════════════════════════════════════════════════════════
            % Alerta duracion
            alertaDur = '';
            if m.num_total > 0
                pct_long = sum(m.duration > 120) / m.num_total * 100;
                if pct_long > 20
                    alertaDur = sprintf('%.0f%% sacadas >120 ms', pct_long);
                end
            end

            % Estado Main Sequence
            if ~isnan(m.mainSeq_r)
                if m.mainSeq_r >= 0.70
                    msEstado = 'Normal';
                elseif m.mainSeq_r >= 0.40
                    msEstado = 'Moderado';
                else
                    msEstado = 'Alterado';
                end
                msR = sprintf('%.2f', m.mainSeq_r);
            else
                msEstado = 'Sin datos (n<3)';
                msR      = 'NaN';
            end

            % Estado parpadeo
            if m.blink_rate < 8
                blinkEstado = 'Posible fatiga';
            elseif m.blink_rate > 25
                blinkEstado = 'Posible irritacion';
            else
                blinkEstado = 'Normal';
            end

            % Estado asimetria
            if m.asymmetry_num > 0.25
                asimTexto = 'Predominio DERECHA';
            elseif m.asymmetry_num < -0.25
                asimTexto = 'Predominio IZQUIERDA';
            else
                asimTexto = 'Equilibrado';
            end

            % Empaquetar struct para el HTML
            obsData = struct( ...
                'dur_media',   sprintf('%.1f ms',   m.mean_duration),  ...
                'dur_val',     m.mean_duration,                        ...
                'amp_media',   sprintf('%.1f mV',   m.mean_amplitude), ...
                'vel_media',   sprintf('%.1f mV/s', m.mean_velocity),  ...
                'vel_der',     sprintf('%.1f mV/s', m.mean_velocity_r),...
                'vel_izq',     sprintf('%.1f mV/s', m.mean_velocity_l),...
                'fix_media',   sprintf('%.0f ms',   m.mean_fixation),  ...
                'fix_val',     m.mean_fixation,                        ...
                'ms_r',        msR,                                    ...
                'ms_val',      m.mainSeq_r,                            ...
                'ms_estado',   msEstado,                               ...
                'asim_idx',    sprintf('%+.2f', m.asymmetry_num),      ...
                'asim_val',    m.asymmetry_num,                        ...
                'asim_texto',  asimTexto,                              ...
                'blink_rate',  sprintf('%.1f /min', m.blink_rate),     ...
                'blink_val',   m.blink_rate,                           ...
                'blink_estado',blinkEstado,                            ...
                'alerta_dur',  alertaDur                               ...
                );

            app.ObsHTML.Data = obsData;
        end
        function setMetricButton(~,btn, nombre, valor, colorValor, bgColor)
            % SETMETRICBUTTON  Formatea un uibutton como tarjeta de métrica.
            %   btn        — handle del uibutton
            %   nombre     — texto superior (nombre de la métrica)
            %   valor      — texto inferior (valor calculado)
            %   colorValor — RGB del texto del valor (verde/naranja/rojo)
            %   bgColor    — RGB del fondo del botón

            btn.Text            = sprintf('%s\n%s', nombre, valor);
            btn.BackgroundColor = bgColor;
            btn.FontColor       = colorValor;
            btn.FontWeight      = 'bold';
            btn.FontSize        = 14;
            btn.WordWrap        = 'on';
            btn.HorizontalAlignment = 'center';
        end

        function actualizarTablaEventosSacadas(app)
            if isempty(app.SaccadesMetrics) || isempty(app.SaccadesMetrics.eventsTable)
                app.EventsTable.Data = table();
                return
            end

            T = app.SaccadesMetrics.eventsTable;

            % Seleccionar y renombrar columnas para visualización clínica
            Tview = table( ...
                round(T.onsetTime,  3), ...
                round(T.offsetTime, 3), ...
                round(T.duration_ms, 1), ...
                round(abs(T.amplitude_mV), 2), ...
                round(T.peakVel_mVs, 1), ...
                T.direction, ...
                'VariableNames', { ...
                'Inicio_s', 'Fin_s', 'Duracion_ms', ...
                'Amplitud_mV', 'VelPico_mVs', 'Direccion'});
            app.EventsTable.Data = Tview;
            app.EventsTable.ColumnName = { ...
                'Inicio (s)', 'Fin (s)', 'Duración (ms)', ...
                'Amplitud (mV)', 'Vel. pico (mV/s)', 'Dirección'};
            % --- Colorear filas según dirección ---
            nRows = size(Tview,1);
            for r = 1:nRows
                sBG = uistyle;
                sBG.FontColor = [0 0 0]; % texto negro

                % Colores suaves por dirección
                if strcmpi(T.direction{r}, 'izquierda')
                    sBG.BackgroundColor = [1 0.8 0.8]; % rojo suave
                elseif strcmpi(T.direction{r}, 'derecha')
                    sBG.BackgroundColor = [0.8 0.9 1]; % azul suave
                else
                    sBG.BackgroundColor = [1 1 1]; % blanco
                end

                addStyle(app.EventsTable, sBG, "row", r);
            end
        end

        function ResetSignals(app)
            if ~isempty(app.TimerGraficar) && isvalid(app.TimerGraficar)
                stop(app.TimerGraficar);
                delete(app.TimerGraficar);
                app.TimerGraficar = [];
            end
            % Flush del buffer antes de empezar (descarta datos acumulados)
            try
                flush(app.Device);
            catch
            end
            % Reset fs state for new recording session
            app.fs_calibrating = true;
            app.fs_fixed       = [];
            app.fs_est         = 0;
            app.lastTime       = [];
            app.counter_fs     = 0;

            app.FilterState = struct();

            % Limpiar líneas animadas anteriores si existen
            if ~isempty(app.LineSHEOL) && isvalid(app.LineSHEOL)
                delete(app.LineSHEOL);
            end
            if ~isempty(app.LineSVEOL) && isvalid(app.LineSVEOL)
                delete(app.LineSVEOL);
            end
            if ~isempty(app.LineSHEOR) && isvalid(app.LineSHEOR)
                delete(app.LineSHEOR);
            end
            if ~isempty(app.LineSVEOR) && isvalid(app.LineSVEOR)
                delete(app.LineSVEOR);
            end
            app.AnalizarMedidasHTML.Visible = "off";
            app.SHEOL = [];
            app.SVEOL = [];
            app.SHEOR = [];
            app.SVEOR = [];

            app.SHEOL_f = [];
            app.SVEOL_f = [];
            app.SHEOR_f = [];
            app.SVEOR_f = [];
            app.SignalsFiltered_OK = false;

            app.HEOG_global = [];
            app.VEOG_global = [];
            app.SaccadesMetrics = [];
        end
        function saved = saveSignals(app)
            saved = false;

            if isempty(app.SHEOL) && isempty(app.SVEOL) && isempty(app.SHEOR) && isempty(app.SVEOR) && ~app.SignalsFiltered_OK
                uialert(app.UIFigure, 'No signals to save.', 'Save', 'Icon', 'warning');
                return
            end

            [file, path] = uiputfile('*.mat', 'Save EOG signals', ...
                sprintf('EOG_%s.mat', datestr(now, 'yyyymmdd_HHMMSS')));

            if isequal(file, 0)
                return  % user cancelled -> saved stays false
            end

            EOG        = struct();
            EOG.fs     = app.fs_fixed;
            EOG.date   = datestr(now);
            EOG.raw.SHEOL = app.SHEOL;
            EOG.raw.SVEOL = app.SVEOL;
            EOG.raw.SHEOR = app.SHEOR;
            EOG.raw.SVEOR = app.SVEOR;

            if app.SignalsFiltered_OK
                EOG.filtered.SHEOL_f = app.SHEOL_f;
                EOG.filtered.SVEOL_f = app.SVEOL_f;
                EOG.filtered.SHEOR_f = app.SHEOR_f;
                EOG.filtered.SVEOR_f = app.SVEOR_f;
            end

            save(fullfile(path, file), 'EOG');
            uialert(app.UIFigure, sprintf('Saved to:\n%s', fullfile(path, file)), ...
                'Saved', 'Icon', 'success');
            saved = true;
        end
        function cargarDebugSignals(app)
            % CARGARDEBUG  Carga un .mat guardado con saveSignals y simula
            % haber pulsado "Analizar Medidas" (AnalisisSignalsScreen + graficas + metricas).

            % ── Seleccionar fichero ───────────────────────────────────────────────
            [file, path] = uigetfile('*.mat', 'Cargar señales EOG guardadas');
            if isequal(file, 0)
                return
            end

            data = load(fullfile(path, file));

            if ~isfield(data, 'EOG')
                uialert(app.UIFigure, ...
                    'El fichero no contiene una variable EOG valida.', ...
                    'Error de carga', 'Icon', 'error');
                return
            end

            EOG = data.EOG;

            if ~isfield(EOG, 'fs') || ~isfield(EOG, 'raw')
                uialert(app.UIFigure, ...
                    'Formato de fichero incorrecto.', ...
                    'Error', 'Icon', 'error');
                return
            end

            % ── Restaurar estado ──────────────────────────────────────────────────
            app.fs_fixed       = EOG.fs;
            app.fs_calibrating = false;
            app.fs_est         = EOG.fs;

            app.SHEOL = EOG.raw.SHEOL;
            app.SVEOL = EOG.raw.SVEOL;
            app.SHEOR = EOG.raw.SHEOR;
            app.SVEOR = EOG.raw.SVEOR;

            if isfield(EOG, 'filtered') && isfield(EOG.filtered, 'SHEOL_f') ...
                    && ~isempty(EOG.filtered.SHEOL_f)
                app.SHEOL_f = EOG.filtered.SHEOL_f;
                app.SVEOL_f = EOG.filtered.SVEOL_f;
                app.SHEOR_f = EOG.filtered.SHEOR_f;
                app.SVEOR_f = EOG.filtered.SVEOR_f;
                app.SignalsFiltered_OK = true;
                fprintf('[DEBUG] Senales filtradas cargadas desde fichero.\n');
            else
                app.SignalsFiltered_OK = false;
                filterSignalsPost(app);
                fprintf('[DEBUG] filterSignalsPost ejecutado sobre crudas.\n');
            end

            app.SaccadesMetrics = [];
            app.HEOG_global     = [];
            app.VEOG_global     = [];

            if ~app.SignalsFiltered_OK
                uialert(app.UIFigure, ...
                    'El filtrado fallo. Comprueba los datos cargados.', ...
                    'Error de filtrado', 'Icon', 'warning');
                return
            end

            fprintf('[DEBUG] fs=%.1f Hz | muestras=%d | duracion=%.1f s\n', ...
                app.fs_fixed, length(app.SHEOR_f), length(app.SHEOR_f)/app.fs_fixed);

            % ── Simular click en "Analizar Medidas" ───────────────────────────────
            AnalizarMedidasClicked(app);
        end
        function exportCSV(app)
            % EXPORTCSV  Exporta el análisis EOG completo en un único fichero CSV.
            %
            % Estructura del fichero (secciones separadas por filas de comentario #):
            %
            %   [SESION]      — parámetros globales de la grabación
            %   [METRICAS]    — métricas clínicas calculadas
            %   [SACADAS]     — tabla evento×evento de cada sacada
            %   [PARPADEOS]   — tabla evento×evento de cada parpadeo
            %   [FIJACIONES]  — tabla evento×evento de cada fijación
            %
            % Cada sección empieza con una fila  # SECCION: <nombre>
            % y sus columnas propias, por lo que se puede parsear programáticamente
            % buscando esa etiqueta.

            % ── Validación ────────────────────────────────────────────────────────
            if ~app.SignalsFiltered_OK
                uialert(app.UIFigure, ...
                    'Primero debes adquirir, filtrar y analizar una señal.', ...
                    'Sin datos para exportar', 'Icon', 'warning');
                return
            end
            if isempty(app.SaccadesMetrics)
                uialert(app.UIFigure, ...
                    'Primero ejecuta el análisis (botón "Analizar Medidas").', ...
                    'Sin métricas calculadas', 'Icon', 'warning');
                return
            end

            m = app.SaccadesMetrics;

            % ── Diálogo de guardado ───────────────────────────────────────────────
            timestamp   = datestr(now, 'yyyymmdd_HHMMSS');
            defaultName = sprintf('HORUS_%s_%s.csv', string(app.usuario), timestamp);

            [file, folder] = uiputfile('*.csv', ...
                'Guardar análisis HORUS como CSV', defaultName);
            if isequal(file, 0)
                return
            end

            filePath = fullfile(folder, file);
            fid = fopen(filePath, 'w', 'n', 'UTF-8');
            if fid == -1
                uialert(app.UIFigure, ...
                    sprintf('No se puede escribir en:\n%s', filePath), ...
                    'Error de escritura', 'Icon', 'error');
                return
            end

            try
                N_muestras = length(app.SHEOR_f);
                duracion_s = N_muestras / app.fs_fixed;

                % ══════════════════════════════════════════════════════════════════
                %  CABECERA GLOBAL
                % ══════════════════════════════════════════════════════════════════
                fprintf(fid, '# HORUS EOG — Exportacion completa de sesion\n');
                fprintf(fid, '# Exportado:,%s\n', ...
                    datestr(now, 'dd/mm/yyyy HH:MM:SS'));
                fprintf(fid, '#\n');
                fprintf(fid, '# Cada seccion comienza con:  # SECCION: <nombre>\n');
                fprintf(fid, '# seguida de una fila de cabeceras y los datos.\n');
                fprintf(fid, '#\n');

                % ══════════════════════════════════════════════════════════════════
                %  SECCIÓN 1 — DATOS DE SESIÓN
                % ══════════════════════════════════════════════════════════════════
                fprintf(fid, '# SECCION: SESION\n');
                fprintf(fid, 'Parametro,Valor,Unidad\n');
                fprintf(fid, 'Usuario,%s,\n',                   string(app.usuario));
                fprintf(fid, 'Frecuencia_muestreo,%.1f,Hz\n',   app.fs_fixed);
                fprintf(fid, 'Duracion_registro,%.2f,s\n',      duracion_s);
                fprintf(fid, 'Muestras_por_canal,%d,\n',        N_muestras);
                fprintf(fid, '#\n');

                % ══════════════════════════════════════════════════════════════════
                %  SECCIÓN 2 — MÉTRICAS CLÍNICAS GLOBALES
                % ══════════════════════════════════════════════════════════════════
                fprintf(fid, '# SECCION: METRICAS\n');
                fprintf(fid, 'Parametro,Valor,Unidad,Interpretacion\n');

                % — Sacadas generales —
                fprintf(fid, 'Sacadas_totales,%d,,\n',              m.num_total);
                fprintf(fid, 'Sacadas_derecha,%d,,\n',              m.num_right);
                fprintf(fid, 'Sacadas_izquierda,%d,,\n',            m.num_left);
                fprintf(fid, 'Frecuencia_sacadas,%.2f,sac/min,\n',  m.frequency_min);
                fprintf(fid, 'Duracion_media,%.1f,ms,\n',           m.mean_duration);
                fprintf(fid, 'Amplitud_media,%.2f,mV,\n',           m.mean_amplitude);
                fprintf(fid, 'Vel_pico_media,%.1f,mV/s,\n',         m.mean_velocity);
                fprintf(fid, 'Vel_pico_media_derecha,%.1f,mV/s,\n', m.mean_velocity_r);
                fprintf(fid, 'Vel_pico_media_izquierda,%.1f,mV/s,\n', m.mean_velocity_l);
                fprintf(fid, 'Umbral_velocidad_T_high,%.1f,mV/s,\n', m.threshold_vel);

                % — Asimetría —
                if m.asymmetry_num > 0.25
                    asimStr = 'Predominio DERECHA';
                elseif m.asymmetry_num < -0.25
                    asimStr = 'Predominio IZQUIERDA';
                else
                    asimStr = 'Equilibrado';
                end
                fprintf(fid, 'Asimetria_DI,%.3f,[-1 a 1],%s\n', ...
                    m.asymmetry_num, asimStr);

                % — Clasificación leve/fuerte —
                if ~isempty(m.eventsTable) && m.num_total > 0
                    T        = m.eventsTable;
                    strongTh = median(abs(T.peakVel_mVs));
                    idxL     = strcmp(string(T.direction), 'izquierda');
                    idxR     = strcmp(string(T.direction), 'derecha');
                    fprintf(fid, 'Umbral_leve_fuerte,%.1f,mV/s,mediana vel. pico\n', strongTh);
                    fprintf(fid, 'Sacadas_leves_izquierda,%d,,\n',   sum(idxL & abs(T.peakVel_mVs) <  strongTh));
                    fprintf(fid, 'Sacadas_fuertes_izquierda,%d,,\n', sum(idxL & abs(T.peakVel_mVs) >= strongTh));
                    fprintf(fid, 'Sacadas_leves_derecha,%d,,\n',     sum(idxR & abs(T.peakVel_mVs) <  strongTh));
                    fprintf(fid, 'Sacadas_fuertes_derecha,%d,,\n',   sum(idxR & abs(T.peakVel_mVs) >= strongTh));
                end

                % — Main sequence —
                if ~isnan(m.mainSeq_r)
                    if m.mainSeq_r >= 0.70
                        msInterp = 'Normal (r>=0.70)';
                    elseif m.mainSeq_r >= 0.40
                        msInterp = 'Moderado (0.40<=r<0.70)';
                    else
                        msInterp = 'Alterado (r<0.40)';
                    end
                    fprintf(fid, 'MainSequence_r,%.4f,,%s\n',    m.mainSeq_r, msInterp);
                    fprintf(fid, 'MainSequence_p,%.4f,,\n',       m.mainSeq_p);
                    if ~isnan(m.mainSeq_slope)
                        fprintf(fid, 'MainSequence_slope,%.4f,log-log,\n', m.mainSeq_slope);
                    end
                else
                    fprintf(fid, 'MainSequence_r,NaN,,Insuficientes sacadas (<3)\n');
                end

                % — Parpadeos —
                if m.blink_rate < 8
                    blinkInterp = 'Posible fatiga (<8/min)';
                elseif m.blink_rate > 25
                    blinkInterp = 'Posible irritacion (>25/min)';
                else
                    blinkInterp = 'Normal (8-25/min)';
                end
                fprintf(fid, 'Parpadeos_totales,%d,,\n',          m.num_blinks);
                fprintf(fid, 'Tasa_parpadeo,%.1f,parl/min,%s\n',  m.blink_rate, blinkInterp);

                % — Fijaciones —
                fprintf(fid, 'Fijaciones_totales,%d,,\n',            m.num_fix);
                fprintf(fid, 'Duracion_media_fijacion,%.0f,ms,\n',   m.mean_fixation);
                fprintf(fid, '#\n');

                % ══════════════════════════════════════════════════════════════════
                %  SECCIÓN 3 — TABLA DE SACADAS
                % ══════════════════════════════════════════════════════════════════
                fprintf(fid, '# SECCION: SACADAS\n');
                fprintf(fid, ['Num,Onset_s,Offset_s,PicoVel_s,' ...
                    'Duracion_ms,Amplitud_abs_mV,' ...
                    'Amplitud_signed_mV,VelPico_mVs,Direccion\n']);

                if ~isempty(m.eventsTable) && m.num_total > 0
                    T = m.eventsTable;
                    for k = 1:m.num_total
                        fprintf(fid, '%d,%.3f,%.3f,%.3f,%.1f,%.2f,%.2f,%.1f,%s\n', ...
                            k, ...
                            T.onsetTime(k), ...
                            T.offsetTime(k), ...
                            T.peakTime(k), ...
                            T.duration_ms(k), ...
                            abs(T.amplitude_mV(k)), ...
                            T.amplitude_mV(k), ...
                            T.peakVel_mVs(k), ...
                            T.direction(k));
                    end
                else
                    fprintf(fid, '# (sin sacadas detectadas)\n');
                end
                fprintf(fid, '#\n');

                % ══════════════════════════════════════════════════════════════════
                %  SECCIÓN 4 — TABLA DE PARPADEOS
                % ══════════════════════════════════════════════════════════════════
                fprintf(fid, '# SECCION: PARPADEOS\n');
                fprintf(fid, 'Num,Onset_s,Offset_s,Duracion_ms,Amplitud_VEOG_mV\n');

                if ~isempty(m.blinksTable) && m.num_blinks > 0
                    B = m.blinksTable;
                    for k = 1:m.num_blinks
                        fprintf(fid, '%d,%.3f,%.3f,%.1f,%.1f\n', ...
                            k, ...
                            B.onsetTime(k), ...
                            B.offsetTime(k), ...
                            B.duration_ms(k), ...
                            B.amplitude_mV(k));
                    end
                else
                    fprintf(fid, '# (sin parpadeos detectados)\n');
                end
                fprintf(fid, '#\n');

                % ══════════════════════════════════════════════════════════════════
                %  SECCIÓN 5 — TABLA DE FIJACIONES
                % ══════════════════════════════════════════════════════════════════
                fprintf(fid, '# SECCION: FIJACIONES\n');
                fprintf(fid, 'Num,Onset_s,Offset_s,Duracion_ms\n');

                if ~isempty(m.fixTable) && m.num_fix > 0
                    F = m.fixTable;
                    for k = 1:m.num_fix
                        fprintf(fid, '%d,%.3f,%.3f,%.1f\n', ...
                            k, ...
                            F.onsetTime(k), ...
                            F.offsetTime(k), ...
                            F.duration_ms(k));
                    end
                else
                    fprintf(fid, '# (sin fijaciones detectadas)\n');
                end
                fprintf(fid, '#\n');

                % ── Cierre y confirmación ─────────────────────────────────────────
                fclose(fid);

                uialert(app.UIFigure, ...
                    sprintf(['Exportación completada.\n\n' ...
                    '%s\n\n' ...
                    '· %d sacadas\n' ...
                    '· %d parpadeos\n' ...
                    '· %d fijaciones'], ...
                    filePath, m.num_total, m.num_blinks, m.num_fix), ...
                    'CSV exportado', 'Icon', 'success');

                fprintf('✓ CSV unificado guardado en: %s\n', filePath);

            catch ME
                fclose(fid);
                uialert(app.UIFigure, ...
                    sprintf('Error durante la exportación:\n%s', ME.message), ...
                    'Error CSV', 'Icon', 'error');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            app.SignalViewSlider.Visible = 'off';
            screenSize = get(0, 'ScreenSize');   % [left bottom width height
            % Get app window size
            figWidth  = app.UIFigure.Position(3);
            figHeight = app.UIFigure.Position(4);
            % Compute centered position
            posX = (screenSize(3) - figWidth) / 2;
            posY = (screenSize(4) - figHeight) / 2;
            % Update UIFigure position
            app.UIFigure.Position(1) = posX;
            app.UIFigure.Position(2) = posY;
            BackButtons = [...
                app.BackChooseActionHTML,...
                app.BackConectHTML,...
                app.BackGraficasHTML,...
                app.BackExpandedViewHTML,...
                app.BackAnalisisHTML,...
                app.BackEventsAnalisisHTML,...
                app.BackAnalisisSignalsHTML
                ];
            for bb = BackButtons
                bb.HTMLSource = 'GoBack.html';
            end
            app.BackChooseActionHTML.Data = struct('id', 'ChooseActionScreen');
            app.BackConectHTML.Data = struct('id', 'ConectScreen');
            app.BackGraficasHTML.Data = struct('id', 'GraficasScreen');
            app.BackExpandedViewHTML.Data = struct('id', 'ExpandedViewScreen');
            app.BackAnalisisHTML.Data = struct('id', 'AnalisisScreen');
            app.BackEventsAnalisisHTML.Data = struct('id', 'EventsAnalisisScreen');
            app.BackAnalisisSignalsHTML.Data = struct('id', 'AnalisisSignalsScreen');

            app.PasswordHTML.HTMLSource = 'TextField.html';
            app.PasswordHTML.Data = struct('type', 'password', 'label', 'Contraseña');
            app.UserNameHTML.HTMLSource = 'TextField.html';
            app.UserNameHTML.Data = struct('type', 'text', 'label', 'Usuario');

            app.WifiStatusHTML.HTMLSource= 'wifi_status.html';
            app.ObsHTML.HTMLSource    = 'observaciones.html';
            app.DevicesListHTML.HTMLSource = 'device_list.html';
            app.LogoHorusHTML.HTMLSource = 'logo_horus.html';
            app.LogoHorusHTML.Data = struct('color', '#808080');
            app.BuscarDevicesHTML.HTMLSource = 'buscar_dispositivo_button.html';
            app.ExportHTML.HTMLSource = "export_button.html";

            buttons = [...
                app.TomarMedidasHTML,...
                app.AccederHTML,...
                app.ConectarGafasHTML,...
                app.VerAnalisisPreviosHTML,...
                app.AnalizarMedidasHTML,...
                app.ResultadosAnalisisHTML,...
                app.VerticalLExpandedHTML,...
                app.VerticalRExpandedHTML,...
                app.HorizontalLExpandedHTML,...
                app.HorizontalRExpandedHTML,...
                app.AnalisisEventosHTML,...
                app.StartStopHTML
                ];
            for b = buttons
                b.HTMLSource = 'Button.html';
            end

            app.StartStopHTML.Data = app.StartAppearance;
            app.AccederHTML.Data = struct( ...
                'bgColor',      '#afeeee', ...
                'textColor',    '#ffffff', ...
                'borderColor',  '#ecffff', ...
                'borderWidth',  '2px',     ...
                'borderRadius', '26px',    ...
                'label',        'Acceder' ...
                );
            app.AnalizarMedidasHTML.Data = struct( ...
                'bgColor',      '#a0c4ff', ...
                'textColor',    '#4b0082', ...
                'borderColor',  '#a0c4ff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'Analizar Medidas' ...
                );
            app.VerticalRExpandedHTML.Data = struct( ...
                'bgColor',      '#b3f0c8', ...
                'textColor',    '#0a5c28', ...
                'borderColor',  '#b3f0c8', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'R Vertical +' ...
                );
            app.HorizontalRExpandedHTML.Data = struct( ...
                'bgColor',      '#b3eaf5', ...
                'textColor',    '#006a80', ...
                'borderColor',  '#b3eaf5', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'R Horizontal +' ...
                );
            app.HorizontalLExpandedHTML.Data = struct( ...
                'bgColor',      '#d9b3f5', ...
                'textColor',    '#3d0070', ...
                'borderColor',  '#d9b3f5', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'L Horizontal +' ...
                );
            app.VerticalLExpandedHTML.Data = struct( ...
                'bgColor',      '#f5bcbc', ...
                'textColor',    '#8b1a1a', ...
                'borderColor',  '#f5bcbc', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'L Vertical +' ...
                );
            app.ResultadosAnalisisHTML.Data = struct( ...
                'bgColor',      '#e3ffe7', ...
                'textColor',    '#4b0082', ...
                'borderColor',  '#bb99ff', ...
                'borderWidth',  '2px',     ...
                'borderRadius', '20px',    ...
                'label',        'Resultados Analisis' ...
                );
            app.TomarMedidasHTML.Data = struct( ...
                'bgColor',      '#afeeee', ...
                'textColor',    '#6a06b3', ...
                'borderColor',  '#9370db', ...
                'borderWidth',  '2px',     ...
                'borderRadius', '18px',    ...
                'label',        'Tomar Medidas' ...
                );
            app.ConectarGafasHTML.Data = struct( ...
                'bgColor',      '#a0c4ff', ...
                'textColor',    '#4b0082', ...
                'borderColor',  '#a0c4ff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '26px',    ...
                'label',        'Conectar Gafas Horus' ...
                );
            app.AnalisisEventosHTML.Data = struct( ...
                'bgColor',      '#cce0ff', ...
                'textColor',    '#ffffff', ...
                'borderColor',  '#cce0ff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '16px',    ...
                'label',        'Analisis Eventos' ...
                );
            app.VerAnalisisPreviosHTML.Data = struct( ...
                'bgColor',      '#a0c4ff', ...
                'textColor',    '#4b0082', ...
                'borderColor',  '#a0c4ff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '26px',    ...
                'label',        'Ver Analisis Previos' ...
                );
            app.EventsTable.ColumnName = { ...
                'Inicio (s)', 'Fin (s)', 'Duración (ms)', ...
                'Amplitud (mV)', 'Vel. pico (mV/s)', 'Dirección'};
            ChangeWindowTo(app,app.MainScreen);
            drawnow
            pause(0.8);
            app.UIFigure.Visible = 'on';
            %cargarDebugSignals(app);
        end

        % Value changed function: ShowHiddenWifisCheckBox
        function ShowHiddenWifisClicked(app, event)
            value = app.ShowHiddenWifisCheckBox.Value;
            if value == 1
                ReloadListWifis(app,app.AvailableWifis);
            else
                ReloadListWifis(app,app.DevicesWifis);
            end
        end

        % Event received function: DevicesListHTML
        function DevicesListClicked(app, event)
            eventData = event.HTMLEventData;
            value = eventData.name;
            ChangeWifiStatus(app,'conectando');
            app.DevicesListHTML.Data = struct('action', 'setState', 'name', value, 'state', 'connecting');
            ConectingWifi = uiprogressdlg(app.UIFigure, ...
                'Title', 'Conectando...', 'Message', 'Conectandose al Dispositivo', 'Indeterminate', 'on');
            drawnow
            for p = app.PasswordOptions
                ConectingWifi.Message = 'Probando Contraseña: ' + p;
                createWifiProfile(app,value,p);

                system("netsh wlan connect name=""" + value + """");

                timeout = 8;
                t0 = tic;

                while toc(t0) < timeout

                    [~,out] = system('netsh wlan show interfaces');

                    % comprobar estado
                    stateLine = regexp(out,'Estado\s*:\s*([^\r\n]*)','tokens','once');

                    % comprobar SSID
                    ssidLine = regexp(out,'SSID\s*:\s*([^\r\n]*)','tokens','once');

                    if ~isempty(stateLine) && ~isempty(ssidLine)

                        state = strtrim(lower(stateLine{1}));
                        currentSSID = strtrim(ssidLine{1});
                        disp("Estado: " + state)
                        disp("SSID: " + currentSSID)

                        if (strcmp(state,"conectado") || strcmp(state,"connected")) && strcmp(currentSSID,value)

                            disp("Conectado con: " + p);
                            close(ConectingWifi);
                            uialert(app.UIFigure,'Dispositivo Conectado','Conexion Hecha','Icon','success');
                            app.TomarMedidasHTML.Visible = 'on';
                            ChangeWifiStatus(app,'conectado');
                            app.LogoHorusHTML.Data = struct('color', '#95F18B');
                            app.DevicesListHTML.Data = struct('action', 'setState', 'name', value, 'state', 'connected');
                            return

                        end

                    end

                    pause(0.3)

                end
                ChangeWifiStatus(app,'error');
                app.LogoHorusHTML.Data = struct('color', '#FF3333');
            end

        end

        % Event received function: BuscarDevicesHTML
        function BuscarDevicesClicked(app, event)
            if strcmp(event.HTMLEventName, "ButtonClicked")
                RefreshWifis(app);
            end
        end

        % Event received function: AccederHTML, AnalisisEventosHTML, 
        % ...and 10 other components
        function ButtonClicked(app, event)
            eventName = event.HTMLEventName;
            eventData = event.HTMLEventData;
            if strcmp(eventName, 'ButtonClicked')
                if strcmp(eventData, 'Tomar Medidas')
                    TomarMedidasClicked(app);
                elseif strcmp(eventData, 'Acceder')
                    AccederClicked(app);
                elseif strcmp(eventData, 'Analizar Medidas')
                    AnalizarMedidasClicked(app);
                elseif strcmp(eventData, 'Resultados Analisis')
                    ResultadosAnalisisClicked(app);
                elseif strcmp(eventData, 'Conectar Gafas Horus')
                    ConectarGafasClicked(app);
                elseif strcmp(eventData, 'Ver Analisis Previos')
                    VerAnalisisPreviosClicked(app);
                elseif strcmp(eventData, 'Analisis Eventos')
                    AnalisisEventosClicked(app);
                elseif strcmp(eventData, 'R Horizontal +')
                    ExpandSignalView(app,eventData);
                elseif strcmp(eventData, 'R Vertical +')
                    ExpandSignalView(app,eventData);
                elseif strcmp(eventData, 'L Horizontal +')
                    ExpandSignalView(app,eventData);
                elseif strcmp(eventData, 'L Vertical +')
                    ExpandSignalView(app,eventData);
                elseif strcmp(eventData, 'Start') | strcmp(eventData, 'Stop')
                    StartStopClicked(app,eventData);
                end
            end
        end

        % Event received function: BackAnalisisHTML, 
        % ...and 6 other components
        function BackButtonClicked(app, event)
            eventName = event.HTMLEventName;
            eventData = event.HTMLEventData.id;
            disp(eventData);
            if strcmp(eventName, 'buttonClicked')
                if strcmp(eventData, 'ChooseActionScreen')
                    BackChooseActionClicked(app);
                elseif strcmp(eventData, 'ConectScreen')
                    BackConectClicked(app);
                elseif strcmp(eventData, 'GraficasScreen')
                    BackGraficasClicked(app);
                elseif strcmp(eventData, 'ExpandedViewScreen')
                    BackExpandedViewClicked(app);
                elseif strcmp(eventData, 'AnalisisScreen')
                    BackAnalisisClicked(app);
                elseif strcmp(eventData, 'EventsAnalisisScreen')
                    BackEventsAnalisisClicked(app);
                elseif strcmp(eventData, 'AnalisisSignalsScreen')
                    BackAnalisisSignalsClicked(app);
                end
            end
        end

        % Close request function: UIFigure
        function closeApp(app, event)
            if ~isempty(app.SHEOL) || ~isempty(app.SVEOL) || ~isempty(app.SHEOR) || ~isempty(app.SVEOR)
                selection = uiconfirm(app.UIFigure, ...
                    'Tienes señales tomadas no procesadas. ¿Quieres guardarlas antes de cerrar?', ...
                    'Guardar señales tomadas', ...
                    'Options',       {'Guardar', 'Cerrar sin guardar', 'Cancelar'}, ...
                    'DefaultOption', 'Guardar', ...
                    'CancelOption',  'Cancelar', ...
                    'Icon',          'question');

                switch selection
                    case 'Guardar'
                        saved = saveSignals(app);
                        if ~saved
                            return  % user cancelled file dialog, abort close
                        end
                    case 'Cancelar'
                        return
                    case 'Cerrar sin guardar'
                        % continue to close
                end
            end

            if ~isempty(app.TimerGraficar) && isvalid(app.TimerGraficar)
                stop(app.TimerGraficar);
                delete(app.TimerGraficar);
            end
            delete(app)
        end

        % Event received function: ExportHTML
        function ExportClicked(app, event)
            format = event.HTMLEventData;
            switch format
                case 'csv'
                    exportCSV(app);
                case 'pdf'
                    disp('Exportar como PDF')
            end
        end

        % Callback function: SignalViewSlider, SignalViewSlider
        function SliderMoving(app, event)
            startT  = event.Value;
            winSize = min(app.ExpandedViewWindow, ...
                app.ExpandedSignalTime(end) - startT);
            app.ExpandedPlot.XLim = [startT, startT + winSize];
        end

        % Callback function: CrearCuentaLink
        function CrearCuentaClicked(app, event)
            if app.crearcuenta

                app.SignInStateLBL.Text = "Ingrese sus datos";
                app.SignInLBL.Text = "Iniciar sesión";
                app.CrearCuentaLink.Text = "Crear cuenta";
                app.crearcuenta = false;
            else
                app.SignInStateLBL.Text = "Elija el su nombre de usuario y contraseña";
                app.SignInLBL.Text = "Crear Cuenta";
                app.CrearCuentaLink.Text = "Iniciar Sesión";
                app.crearcuenta = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeApp, true);
            app.ChooseActionScreen = uipanel(app.UIFigure);
            app.ChooseActionGrid = uigridlayout(app.ChooseActionScreen);
            app.Image = uiimage(app.ChooseActionGrid);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'GAFAS-HORUS-LOGO.svg');
            app.Image2 = uiimage(app.ChooseActionGrid);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'patient.svg');
            app.VerAnalisisPreviosHTML = uihtml(app.ChooseActionGrid);
            app.VerAnalisisPreviosHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.ConectarGafasHTML = uihtml(app.ChooseActionGrid);
            app.ConectarGafasHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.BackChooseActionHTML = uihtml(app.ChooseActionGrid);
            app.BackChooseActionHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.EventsAnalisisScreen = uipanel(app.UIFigure);
            app.EventsAnalisisGrid = uigridlayout(app.EventsAnalisisScreen);
            app.EventsAnalisisLBL = uilabel(app.EventsAnalisisGrid);
            app.EventsAnalisisLBL.Text = 'Analisis de Eventos Oculares';
            app.EventsTable = uitable(app.EventsAnalisisGrid);
            app.EventsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.EventsTable.RowName = {};
            app.BackEventsAnalisisHTML = uihtml(app.EventsAnalisisGrid);
            app.BackEventsAnalisisHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.GraficasScreen = uipanel(app.UIFigure);
            app.GraficasGrid = uigridlayout(app.GraficasScreen);
            app.VEOR = uiaxes(app.GraficasGrid);
            title(app.VEOR, 'VEOR')
            zlabel(app.VEOR, 'Z')
            app.HEOL = uiaxes(app.GraficasGrid);
            title(app.HEOL, 'HEOL')
            zlabel(app.HEOL, 'Z')
            app.HEOR = uiaxes(app.GraficasGrid);
            title(app.HEOR, 'HEOR')
            zlabel(app.HEOR, 'Z')
            app.VEOL = uiaxes(app.GraficasGrid);
            title(app.VEOL, 'VEOL')
            zlabel(app.VEOL, 'Z')
            app.ConectedLampGrid = uigridlayout(app.GraficasGrid);
            app.ConectedLBL = uilabel(app.ConectedLampGrid);
            app.ConectedLBL.Text = 'Conectado';
            app.ConectedLamp = uilamp(app.ConectedLampGrid);
            app.EYER = uiimage(app.GraficasGrid);
            app.EYER.ImageSource = fullfile(pathToMLAPP, 'EYE-HORUS-L.svg');
            app.EYEL = uiimage(app.GraficasGrid);
            app.EYEL.ImageSource = fullfile(pathToMLAPP, 'EYE-HORUS-R.svg');
            app.AnalizarMedidasHTML = uihtml(app.GraficasGrid);
            app.AnalizarMedidasHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.BackGraficasHTML = uihtml(app.GraficasGrid);
            app.BackGraficasHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.FSLabel = uilabel(app.GraficasGrid);
            app.FSLabel.Text = 'Label3';
            app.StartStopHTML = uihtml(app.GraficasGrid);
            app.StartStopHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.ExpandedViewScreen = uipanel(app.UIFigure);
            app.ExpandedViewGrid = uigridlayout(app.ExpandedViewScreen);
            app.ExpandedPlot = uiaxes(app.ExpandedViewGrid);
            title(app.ExpandedPlot, 'Signal View')
            xlabel(app.ExpandedPlot, 'X')
            ylabel(app.ExpandedPlot, 'Y')
            zlabel(app.ExpandedPlot, 'Z')
            colormap(app.ExpandedPlot, 'winter')
            app.BackExpandedViewHTML = uihtml(app.ExpandedViewGrid);
            app.BackExpandedViewHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.SignalViewLabel = uilabel(app.ExpandedViewGrid);
            app.SignalViewLabel.Text = 'Signal View';
            app.SignalViewSlider = uislider(app.ExpandedViewGrid);
            app.SignalViewSlider.Limits = [0 1];
            app.SignalViewSlider.MajorTicks = [];
            app.SignalViewSlider.ValueChangedFcn = createCallbackFcn(app, @SliderMoving, true);
            app.SignalViewSlider.ValueChangingFcn = createCallbackFcn(app, @SliderMoving, true);
            app.SignalViewSlider.MinorTicks = [0 0.005 0.01 0.015 0.02 0.025 0.03 0.035 0.04 0.045 0.05 0.055 0.06 0.065 0.07 0.075 0.08 0.085 0.09 0.095 0.1 0.105 0.11 0.115 0.12 0.125 0.13 0.135 0.14 0.145];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
            app.AnalisisSignalsScreen = uipanel(app.UIFigure);
            app.AnalisisSignalsGrid = uigridlayout(app.AnalisisSignalsScreen);
            app.LVerticalAxis = uiaxes(app.AnalisisSignalsGrid);
            title(app.LVerticalAxis, 'L Vertical')
            zlabel(app.LVerticalAxis, 'Z')
            app.LHorizontalAxis = uiaxes(app.AnalisisSignalsGrid);
            title(app.LHorizontalAxis, 'L Horizontal')
            zlabel(app.LHorizontalAxis, 'Z')
            app.RVerticalAxis = uiaxes(app.AnalisisSignalsGrid);
            title(app.RVerticalAxis, 'R Vertical')
            zlabel(app.RVerticalAxis, 'Z')
            app.RHorizontalAxis = uiaxes(app.AnalisisSignalsGrid);
            title(app.RHorizontalAxis, 'R Horizontal')
            zlabel(app.RHorizontalAxis, 'Z')
            app.EYER2 = uiimage(app.AnalisisSignalsGrid);
            app.EYER2.ImageSource = fullfile(pathToMLAPP, 'EYE-HORUS-L.svg');
            app.EYEL2 = uiimage(app.AnalisisSignalsGrid);
            app.EYEL2.ImageSource = fullfile(pathToMLAPP, 'EYE-HORUS-R.svg');
            app.HorizontalLExpandedHTML = uihtml(app.AnalisisSignalsGrid);
            app.HorizontalLExpandedHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.HorizontalRExpandedHTML = uihtml(app.AnalisisSignalsGrid);
            app.HorizontalRExpandedHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.VerticalLExpandedHTML = uihtml(app.AnalisisSignalsGrid);
            app.VerticalLExpandedHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.VerticalRExpandedHTML = uihtml(app.AnalisisSignalsGrid);
            app.VerticalRExpandedHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.ResultadosAnalisisHTML = uihtml(app.AnalisisSignalsGrid);
            app.ResultadosAnalisisHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.BackAnalisisSignalsHTML = uihtml(app.AnalisisSignalsGrid);
            app.BackAnalisisSignalsHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.ConectScreen = uipanel(app.UIFigure);
            app.ConectGrid = uigridlayout(app.ConectScreen);
            app.ShowHiddenWifisCheckBox = uicheckbox(app.ConectGrid);
            app.ShowHiddenWifisCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowHiddenWifisClicked, true);
            app.ShowHiddenWifisCheckBox.Text = 'Enseñar Redes Escondidas';
            app.WifiStatusHTML = uihtml(app.ConectGrid);
            app.DevicesListHTML = uihtml(app.ConectGrid);
            app.DevicesListHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @DevicesListClicked, true);
            app.LogoHorusHTML = uihtml(app.ConectGrid);
            app.BuscarDevicesHTML = uihtml(app.ConectGrid);
            app.BuscarDevicesHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BuscarDevicesClicked, true);
            app.TomarMedidasHTML = uihtml(app.ConectGrid);
            app.TomarMedidasHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.BackConectHTML = uihtml(app.ConectGrid);
            app.BackConectHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.AnalisisScreen = uipanel(app.UIFigure);
            app.AnalisisGrid = uigridlayout(app.AnalisisScreen);
            app.ANALISISEOGLabel = uilabel(app.AnalisisGrid);
            app.ANALISISEOGLabel.Text = '            ANÁLISIS EOG';
            app.Image_2 = uiimage(app.AnalisisGrid);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'PERSON-ICON-WHITE.png');
            app.BackAnalisisHTML = uihtml(app.AnalisisGrid);
            app.BackAnalisisHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.AnalisisEventosHTML = uihtml(app.AnalisisGrid);
            app.AnalisisEventosHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.FechaLabel = uilabel(app.AnalisisGrid);
            app.FechaLabel.Text = 'Fecha:';
            app.DuracionLabel = uilabel(app.AnalisisGrid);
            app.DuracionLabel.Text = 'Duracion:';
            app.CorrelacindeSpearmanPanel = uipanel(app.AnalisisGrid);
            app.CorrelacindeSpearmanPanel.Title = ' Correlación de Spearman';
            app.GridLayout = uigridlayout(app.CorrelacindeSpearmanPanel);
            app.CorrAxes = uiaxes(app.GridLayout);
            xlabel(app.CorrAxes, 'Amplitud (mV)')
            ylabel(app.CorrAxes, 'Vel. pico (mV/s)')
            zlabel(app.CorrAxes, 'Z')
            app.RresultLBL = uilabel(app.GridLayout);
            app.RresultLBL.Text = 'R=';
            app.TasadeparpadeoPanel = uipanel(app.AnalisisGrid);
            app.TasadeparpadeoPanel.Title = 'Tasa de parpadeo';
            app.GridLayout_2 = uigridlayout(app.TasadeparpadeoPanel);
            app.Gauge = uigauge(app.GridLayout_2, 'semicircular');
            app.Gauge.Limits = [0 40];
            app.Gauge.MajorTicks = [0 4 8 14 20 25 30 35 40];
            app.Gauge.MajorTickLabels = {'0', '4', '8', '14', '20', '25', '30', '35', '40'};
            app.Gauge.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40];
            app.ParpadeosminLabel = uilabel(app.GridLayout_2);
            app.ParpadeosminLabel.Text = 'Parpadeos/min';
            app.ParpadeostotalesLabel = uilabel(app.GridLayout_2);
            app.ParpadeostotalesLabel.Text = 'Parpadeos totales';
            app.RateParpadeosLBL = uilabel(app.GridLayout_2);
            app.RateParpadeosLBL.Text = 'Label8';
            app.TotalParpadeosLBL = uilabel(app.GridLayout_2);
            app.TotalParpadeosLBL.Text = 'Label9';
            app.MetricasclavePanel = uipanel(app.AnalisisGrid);
            app.MetricasclavePanel.Title = 'Metricas clave';
            app.GridLayout_3 = uigridlayout(app.MetricasclavePanel);
            app.SacadastotalesButton = uibutton(app.GridLayout_3, 'push');
            app.SacadastotalesButton.Text = 'Sacadas totales';
            app.DurationmediaButton = uibutton(app.GridLayout_3, 'push');
            app.DurationmediaButton.Text = 'Duración media';
            app.ParpadeosButton = uibutton(app.GridLayout_3, 'push');
            app.ParpadeosButton.Text = 'Parpadeos';
            app.FijacionMediaButton = uibutton(app.GridLayout_3, 'push');
            app.FijacionMediaButton.Text = 'Fijacion Media';
            app.AsimetriaButton = uibutton(app.GridLayout_3, 'push');
            app.AsimetriaButton.Text = 'Asimetria';
            app.CorrelationButton = uibutton(app.GridLayout_3, 'push');
            app.CorrelationButton.Text = 'Correlación';
            app.AsimetriaIzquierdaDerechaPanel = uipanel(app.AnalisisGrid);
            app.AsimetriaIzquierdaDerechaPanel.Title = 'Asimetria Izquierda/Derecha';
            app.GridLayout_4 = uigridlayout(app.AsimetriaIzquierdaDerechaPanel);
            app.AsimetriaAxes = uiaxes(app.GridLayout_4);
            zlabel(app.AsimetriaAxes, 'Z')
            app.AsimetriaAxes.XLim = [-1.1 1.1];
            app.AsimetriaAxes.YLim = [-1 1];
            app.AsimetriaAxes.YTick = [];
            app.ObservacionesPanel = uipanel(app.AnalisisGrid);
            app.ObservacionesPanel.Title = 'Observaciones';
            app.GridLayout_5 = uigridlayout(app.ObservacionesPanel);
            app.ObsHTML = uihtml(app.GridLayout_5);
            app.FrecuenciaLabel = uilabel(app.AnalisisGrid);
            app.FrecuenciaLabel.Text = 'Frecuencia:';
            app.TimeLBL = uilabel(app.AnalisisGrid);
            app.TimeLBL.Text = 'Label5';
            app.DurationLBL = uilabel(app.AnalisisGrid);
            app.DurationLBL.Text = 'Label6';
            app.FrequencyLBL = uilabel(app.AnalisisGrid);
            app.FrequencyLBL.Text = 'Label7';
            app.ExportHTML = uihtml(app.AnalisisGrid);
            app.ExportHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ExportClicked, true);
            app.MainScreen = uipanel(app.UIFigure);
            app.MainGrid = uigridlayout(app.MainScreen);
            app.SignInLBL = uilabel(app.MainGrid);
            app.SignInLBL.Text = 'Inicia Sesión';
            app.LOGOSEPA = uiimage(app.MainGrid);
            app.LOGOSEPA.ImageSource = fullfile(pathToMLAPP, 'LOGO-SEPA-EXPANDED.png');
            app.SignInStateLBL = uilabel(app.MainGrid);
            app.SignInStateLBL.Text = 'Sesion Iniciada Correctamente';
            app.CrearCuentaLink = uihyperlink(app.MainGrid);
            app.CrearCuentaLink.HyperlinkClickedFcn = createCallbackFcn(app, @CrearCuentaClicked, true);
            app.CrearCuentaLink.Text = 'Crear Cuenta';
            app.AccederHTML = uihtml(app.MainGrid);
            app.AccederHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.ConectionCableCheckBox = uicheckbox(app.MainGrid);
            app.ConectionCableCheckBox.Text = 'Conexión por cable';
            app.UserNameHTML = uihtml(app.MainGrid);
            app.PasswordHTML = uihtml(app.MainGrid);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HORUS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end